classdef Algoritmo_HU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AlgoritmoHUPanel        matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        LoadImageButton         matlab.ui.control.Button
        ImageforparameterLabel  matlab.ui.control.Label
        HUAlghoritmButton       matlab.ui.control.Button
        DImageButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        % -------------------------- funzione Hu_Momements --------------------------
        function phi = Hu_Moments(~, F)
            %INVMOMENTS Compute invariant moments of image.
            %   PHI = INVMOMENTS(F) computes the moment invariants of the image
            %   F. PHI is a seven-element row vector containing the moment
            %   invariants as defined in equations (11.3-17) through (11.3-23) of
            %   Gonzalez and Woods, Digital Image Processing, 2nd Ed.
            %
            %   F must be a 2-D, real, nonsparse, numeric or logical matrix.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/11/21 14:39:19 $
            
            if (ndims(F) ~= 2) || issparse(F) || ~isreal(F) || ~(isnumeric(F) || islogical(F))
               error(['F must be a 2-D, real, nonsparse, numeric or logical ' 'matrix.']);                       
            end
            
            F = double(F);
            phi = compute_phi(compute_eta(compute_m(F)));            
                
           
            % -------------------------- funzione compute_m --------------------------
            function m = compute_m(~, F)
                [M, N] = size(F);
                [x, y] = meshgrid(1:N, 1:M);
                  
                % Turn x, y, and F into column vectors to make the summations a bit
                % easier to compute in the following.
                x = x(:);
                y = y(:);
                F = F(:);
                  
                % DIP equation (11.3-12)
                m.m00 = sum(F);
                % Protect against divide-by-zero warnings.
                if (m.m00 == 0)
                   m.m00 = eps;
                end
                % The other central moments:  
                m.m10 = sum(x .* F);
                m.m01 = sum(y .* F);
                m.m11 = sum(x .* y .* F);
                m.m20 = sum(x.^2 .* F);
                m.m02 = sum(y.^2 .* F);
                m.m30 = sum(x.^3 .* F);
                m.m03 = sum(y.^3 .* F);
                m.m12 = sum(x .* y.^2 .* F);
                m.m21 = sum(x.^2 .* y .* F);            
            end
            
            % -------------------------- funzione compute_eta --------------------------
            function e = compute_eta(~, m)
    
                % DIP equations (11.3-14) through (11.3-16).
                
                xbar = m.m10 / m.m00;
                ybar = m.m01 / m.m00;
                
                e.eta11 = (m.m11 - ybar*m.m10) / m.m00^2;
                e.eta20 = (m.m20 - xbar*m.m10) / m.m00^2;
                e.eta02 = (m.m02 - ybar*m.m01) / m.m00^2;
                e.eta30 = (m.m30 - 3 * xbar * m.m20 + 2 * xbar^2 * m.m10) / m.m00^2.5;
                e.eta03 = (m.m03 - 3 * ybar * m.m02 + 2 * ybar^2 * m.m01) / m.m00^2.5;
                e.eta21 = (m.m21 - 2 * xbar * m.m11 - ybar * m.m20 + 2 * xbar^2 * m.m01) / m.m00^2.5;                       
                e.eta12 = (m.m12 - 2 * ybar * m.m11 - xbar * m.m02 + 2 * ybar^2 * m.m10) / m.m00^2.5;                               
            end
            
            % -------------------------- funzione compute_phi --------------------------
            function phi = compute_phi(~, e)
                % DIP equations (11.3-17) through (11.3-23).
                
                phi(1) = e.eta20 + e.eta02;
                phi(2) = (e.eta20 - e.eta02)^2 + 4*e.eta11^2;
                phi(3) = (e.eta30 - 3*e.eta12)^2 + (3*e.eta21 - e.eta03)^2;
                phi(4) = (e.eta30 + e.eta12)^2 + (e.eta21 + e.eta03)^2;
                phi(5) = (e.eta30 - 3*e.eta12) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                %phi(5) = (e.eta30 - 3*e.eta21) * (e.eta30 + e.eta12) * ...
                %          ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                %          (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                %          ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                phi(6) = (e.eta20 - e.eta02) * ( (e.eta30 + e.eta12)^2 - ...
                                                 (e.eta21 + e.eta03)^2 ) + ...
                         4 * e.eta11 * (e.eta30 + e.eta12) * (e.eta21 + e.eta03);
                phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                % phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                %          ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) - ...
                %          (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                %          ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                            
            end
        end
        
        % -------------------------- Funzione per trasformare in scala di grigi --------------------------
        function grayscale(app)
            global image1
            image1 = rgb2gray(image1);
            imshow(image1, 'Parent', app.UIAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global image1
            
            [file, path] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            
            fullpathname = strcat(path, file);
            
            image1 = imread(fullpathname);
         
            imshow(image1, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: HUAlghoritmButton
        function HUAlghoritmButtonPushed(app, event)
            global image1
                      
            image1 = app.Hu_Moments(image1);
            
            % -------------------------- Risolvere il problema degli input --------------------------
            
            imshow(image1, 'Parent', app.UIAxes);
        end

        % Button pushed function: DImageButton
        function DImageButtonPushed(app, event)
            grayscale(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AlgoritmoHUPanel = uipanel(app.UIFigure);
            app.AlgoritmoHUPanel.Tooltip = {''};
            app.AlgoritmoHUPanel.Title = 'Algoritmo HU';
            app.UIAxes = uiaxes(app.AlgoritmoHUPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadImageButton = uibutton(app.AlgoritmoHUPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'IconaCartella.png';
            app.LoadImageButton.Text = {'Load Image'; ''};
            app.ImageforparameterLabel = uilabel(app.AlgoritmoHUPanel);
            app.ImageforparameterLabel.Text = 'Image for parameter';
            app.HUAlghoritmButton = uibutton(app.AlgoritmoHUPanel, 'push');
            app.HUAlghoritmButton.ButtonPushedFcn = createCallbackFcn(app, @HUAlghoritmButtonPushed, true);
            app.HUAlghoritmButton.Text = {'HU Alghoritm'; ''};
            app.DImageButton = uibutton(app.AlgoritmoHUPanel, 'push');
            app.DImageButton.ButtonPushedFcn = createCallbackFcn(app, @DImageButtonPushed, true);
            app.DImageButton.Text = '2-D Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Algoritmo_HU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end