classdef Zernike < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LoadImageTab          matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        LoadImageButton       matlab.ui.control.Button
        Convertin2DButton     matlab.ui.control.Button
        ZernikeMomentsImage   matlab.ui.control.Button
        ImageLabel            matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        LoadFolderTab         matlab.ui.container.Tab
        LoadFolderButton      matlab.ui.control.Button
        DirectoryPathLabel    matlab.ui.control.Label
        DirectoryContents     matlab.ui.control.Table
        ZernikeMomentsFolder  matlab.ui.control.Button
        Features_Dataset      matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        % -------------------------- Funzione per trasformare in scala di grigi --------------------------
        function grayscale(app)
            global image1
            image1 = rgb2gray(image1);
            imshow(image1, 'Parent', app.UIAxes);
        end
        
        % -------------------------- Funzione Zernike_Moments --------------------------
        function [zernike_mom] = Zernike_Moments(~,f, ord, repet)
            % Function to compute Zernike moments up to order ord.
            %       f = Input image M x N matrix (can be grayscale or color)
            %       ord = The maximum order (scalar)
            %       rep = repetition  number
            %       zernike_mom = Vector containing all the moments up to order ord
            %   
            % EXAMPLE
            %       
            %   RGBfile = imgetfile();
            %   RGB = imread(RGBfile);
            %   if size(RGB, 3)==1
            %       gray = RGB;
            %   else
            %       gray = rgb2gray(RGB);
            %   end
            %   ord = 4;
            %   repet = 2;
            %   mom = Zernike_Moments(gray, ord, repet);
            %   mom2 = Zernike_Moments(imrotate(gray, 90), ord, repet);
            %   mom3 = Zernike_Moments(imresize(gray, 0.5), ord, repet);
            %   MOM = [mom;mom2;mom3];
            if size(f,3)==3  
                f = rgb2gray(f); 
            end

            f = double(f);
            zernike_mom = [];

            for i=0:ord
                for j=0:repet
                    if i>=j && mod((i+j),2)==0
                        % Computation of Zernike moment with order i and repetition j 
                        [~, AOH, ~] = Zernikmoment_MN(f,i,j); 
                        zernike_mom = [zernike_mom AOH];
                    end
                end
            end  
        
            % -------------------------- Funzione Zernikemoment_MN --------------------------
            function [Z, A, Phi] = Zernikmoment_MN(f,n,m)
                % Function to find the Zernike moments for an M x N grayscale image
                %       f = Input image M x N matrix 
                %       n = The order of Zernike moment (scalar)
                %       m = The repetition number of Zernike moment (scalar)
                %       Z = Complex Zernike moment 
                %       A = Amplitude of the moment
                %       Phi = phase (angle) of the moment (in degrees)
                %
                [M, N]  = size(f);
                y = 1:M; x = 1:N;  % x row, y col

                [X,Y] = meshgrid(x,y);
                R = sqrt( (2.*X-N-1).^2/N^2 + (2.*Y-M-1).^2/M^2 );
                Theta = atan2((M-1-2.*Y+2)/M,(2.*X-N+1-2)/N);

                R = (R<=1).*R;
                Rad = radialpoly(R,n,m);    % get the radial polynomial
                Product = f.*Rad.*exp(-1i*m*Theta);
                Z = sum(Product(:));        % calculate the moments
 
                cnt = nnz(R)+1;             % count the number of pixels inside the unit circle
                Z = (n+1)*Z/cnt;            % normalize the amplitude of moments
                A = abs(Z);                 % calculate the amplitude of the moment
                Phi = angle(Z)*180/pi;      % calculate the phase of the moment (in degrees)
            end
            
            % -------------------------- Funzione radialpoly --------------------------
            function rad = radialpoly(r,n,m)
                % Function to compute Zernike Polynomials:
                %       r = radius
                %       n = the order of Zernike polynomial
                %       m = the repetition of Zernike moment
                rad = zeros(size(r));                     % Initilization
                for s = 0:(n-abs(m))/2
                    c = (-1)^s*factorial(n-s)/(factorial(s)*factorial((n+abs(m))/2-s)*factorial((n-abs(m))/2-s));
                rad = rad + c*r.^(n-2*s);
                end
            end
        end
        
        
        function zernike(app, image1, ord, riga, table)
            for i = 0:ord    % ciclo in base al valore di ord  
               % il calcolo quando i vale 0 o 1 è pressoché lo stesso 
               if i == 0
                   rep = 0;  % fisso il valore di rep a zero giusto per non avere magic number
                   Risultati = app.Zernike_Moments(image1,i,rep);
                   table.Data(riga, 1:1) = Risultati; 
                   riga = riga + 1;               
               elseif i == 1
                   rep = 1;
                   Risultati = app.Zernike_Moments(image1,i,rep);
                   table.Data(riga, 1:2) = Risultati; 
                   riga = riga + 1;               
               else
                   % quando i > 1, si dovrà ripetere fino a rep = i
                   % non faccio il controllo quando ord-rep è pari per una
                   % questone di comodità visto che per i test i valori
                   % sono decisi in anticipo e di conseguenza si conoscono
                   % già le possibili combinazioni
                   rep = i; % in questo modo sappiamo quante volte ciclare quando i > 1                   
                   for j = 0:rep
                       switch rep
                           case 2
                               if j == 0  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:2) = Risultati; 
                                   riga = riga + 1; 
                               end
                               if j == 2  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:4) = Risultati; 
                                   riga = riga + 1; 
                               end
                           case 3
                               if j == 1  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:4) = Risultati; 
                                   riga = riga + 1; 
                               end
                               if j == 3  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:6) = Risultati; 
                                   riga = riga + 1; 
                               end                           
                           case 4
                               if j == 0  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:3) = Risultati; 
                                   riga = riga + 1; 
                               end
                               if j == 2  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:7) = Risultati; 
                                   riga = riga + 1; 
                               end
                               if j == 4  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:9) = Risultati; 
                                   riga = riga + 1; 
                               end                               
                           case 5
                               if j == 1  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:6) = Risultati; 
                                   riga = riga + 1; 
                               end
                               if j == 3  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(riga, 1:10) = Risultati; 
                                   riga = riga + 1; 
                               end         
                               if j == 5  
                                   Risultati = app.Zernike_Moments(image1,i,j);
                                   table.Data(1, 1:12) = Risultati;
                                   riga = riga + 1;
                               end         
                       end
                   end
               end            
            end                    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global image1     
            
            filterImage = {'*.tiff;*.jpg;*.png;*.gif;*.tif;*.bmp', 'All Image Files'}; %imposto il filtro per la scelta delle immagini (in base al tipo di file)
            
            [file, path] =  uigetfile(filterImage); % l'utente sceglie un'immagine sulla base del filtro presente
            
            % con questo controllo faccio in modo che l'applicazione non
            % vada avanti fino a quando l'utente non inserisce un'immagine,
            % evitando il crash nel caso in cui decida di provare a uscire
            % dalla finestra di dialogo che viene generata
            if(ischar(path))
                fullpathname = strcat(path, file);
                image1 = imread(fullpathname);
            end
            
            imshow(image1, 'Parent', app.UIAxes); % mostro a video l'immagine al posto del piano
        end

        % Button pushed function: Convertin2DButton
        function Convertin2DButtonPushed(app, event)
            grayscale(app);
        end

        % Button pushed function: ZernikeMomentsImage
        function ZernikeMomentsImagePushed(app, event)
            global image1;
            ord = 5; % fisso un valore massimo 
            riga = 1; % contatore delle righe della tabella
            
            app.zernike(image1, ord, riga, app.UITable);
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
             % variabili globali 
            global path            
            
            path = uigetdir;                       % 'uigetdir' recupera il path in base alla cartella selezionata dall'utente
            
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
            
            % inserito lo stesso controllo presente nell'inserimento
            % dell'immagine, in modo che non venga generato un errore nel
            % caso in cui l'utente dovesse premere per sbaglio sul tasto
            % per annullare la selezione di una cartella
            
            if(ischar(path))
                app.DirectoryPathLabel.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 

                a = dir(path);                                      % recupero il contenuto della cartella attraverso il path e la funzione 'dir'
                imageVector = {a(:).name}';                         % recupero tutti i nomi dei vari file presenti nella cartella e li salvo in un array
                imageVector(ismember(imageVector,{'.','..'})) = []; % rimuovo eventuali 'punti' o 'doppi punti' che non sono necessari

                app.DirectoryContents.Data = imageVector;           % mostro il contenuto della cartella nella tabella "Directory Contents"
            end                               
        end

        % Button pushed function: ZernikeMomentsFolder
        function ZernikeMomentsFolderPushed(app, event)
            global path
            
            ord = 5;
            riga = 1;
            
            % mancano risultati e features per il confronto con la distanza
            % euclidea
            
            pattern = fullfile(path, '*tiff');
            immagini = dir(pattern);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                fullFileName = fullfile(path, baseFileName);
                image = imread(fullFileName);                        
                app.zernike(image, ord, riga, app.Features_Dataset); 
                riga = riga + 14;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImageTab = uitab(app.TabGroup);
            app.LoadImageTab.Title = 'Load Image';
            app.UIAxes = uiaxes(app.LoadImageTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.LoadImageTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'Icon_Folder.png';
            app.LoadImageButton.Text = {'     Load Image'; ''};
            app.Convertin2DButton = uibutton(app.LoadImageTab, 'push');
            app.Convertin2DButton.ButtonPushedFcn = createCallbackFcn(app, @Convertin2DButtonPushed, true);
            app.Convertin2DButton.Text = {'Convert in 2-D'; ''};
            app.ZernikeMomentsImage = uibutton(app.LoadImageTab, 'push');
            app.ZernikeMomentsImage.ButtonPushedFcn = createCallbackFcn(app, @ZernikeMomentsImagePushed, true);
            app.ZernikeMomentsImage.Text = {'Zernike Moments'; ''};
            app.ImageLabel = uilabel(app.LoadImageTab);
            app.ImageLabel.Text = {'Image'; ''};
            app.UITable = uitable(app.LoadImageTab);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UITable.RowName = {};
            app.LoadFolderTab = uitab(app.TabGroup);
            app.LoadFolderTab.Title = 'Load Folder';
            app.LoadFolderButton = uibutton(app.LoadFolderTab, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Icon = 'Icon_Folder.png';
            app.LoadFolderButton.Text = {'     Load Folder'; ''};
            app.DirectoryPathLabel = uilabel(app.LoadFolderTab);
            app.DirectoryPathLabel.Text = {'    Directory Path'; ''};
            app.DirectoryContents = uitable(app.LoadFolderTab);
            app.DirectoryContents.ColumnName = {'Directory Content'};
            app.DirectoryContents.RowName = {};
            app.ZernikeMomentsFolder = uibutton(app.LoadFolderTab, 'push');
            app.ZernikeMomentsFolder.ButtonPushedFcn = createCallbackFcn(app, @ZernikeMomentsFolderPushed, true);
            app.ZernikeMomentsFolder.Text = {'Zernike Moments'; ''};
            app.Features_Dataset = uitable(app.LoadFolderTab);
            app.Features_Dataset.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.Features_Dataset.RowName = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zernike

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end