classdef Moments_Applications < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HU_ApplicationTab               matlab.ui.container.Tab
        LoadFolderButton                matlab.ui.control.Button
        DirectoryPathLabel              matlab.ui.control.Label
        DirectoryContents               matlab.ui.control.Table
        AlgorithmHUButton               matlab.ui.control.Button
        Features_Dataset                matlab.ui.control.Table
        Euclidean_Results               matlab.ui.control.Table
        LoadImageButton                 matlab.ui.control.Button
        photoPicked_2                   matlab.ui.control.Label
        EuclideanDistanceButton         matlab.ui.control.Button
        Zernike_ApplicationTab          matlab.ui.container.Tab
        LoadFolderButton_2              matlab.ui.control.Button
        DirectoryPathLabel_2            matlab.ui.control.Label
        DirectoryContents_2             matlab.ui.control.Table
        AlgorithmZernikeButton          matlab.ui.control.Button
        Features_Dataset_2              matlab.ui.control.Table
        Euclidean_Results_2             matlab.ui.control.Table
        LoadImageButton_2               matlab.ui.control.Button
        photoPicked_3                   matlab.ui.control.Label
        EuclideanDistanceZernikeButton  matlab.ui.control.Button
        GetOrderEditFieldLabel          matlab.ui.control.Label
        GetOrderEditField               matlab.ui.control.NumericEditField
        GetRepEditFieldLabel            matlab.ui.control.Label
        GetRepEditField                 matlab.ui.control.NumericEditField
        SimilarityButton                matlab.ui.control.Button
        Similarity_Results              matlab.ui.control.Table
        HU_ResultsTab                   matlab.ui.container.Tab
        ImageforComparisonwithHULabel   matlab.ui.control.Label
        photoPicked                     matlab.ui.control.Label
        photo1                          matlab.ui.control.UIAxes
        photo2                          matlab.ui.control.UIAxes
        photo3                          matlab.ui.control.UIAxes
        photo4                          matlab.ui.control.UIAxes
        photo5                          matlab.ui.control.UIAxes
        photo6                          matlab.ui.control.UIAxes
        photo7                          matlab.ui.control.UIAxes
        photo8                          matlab.ui.control.UIAxes
        photo9                          matlab.ui.control.UIAxes
        photo10                         matlab.ui.control.UIAxes
        dates1                          matlab.ui.control.Table
        dates2                          matlab.ui.control.Table
        dates3                          matlab.ui.control.Table
        dates4                          matlab.ui.control.Table
        dates5                          matlab.ui.control.Table
        dates6                          matlab.ui.control.Table
        dates7                          matlab.ui.control.Table
        dates8                          matlab.ui.control.Table
        dates9                          matlab.ui.control.Table
        dates10                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        Zernike_ResultsTab              matlab.ui.container.Tab
        ImageforComparisonwithZernikeLabel  matlab.ui.control.Label
        photoPicked_4                   matlab.ui.control.Label
        photo1_2                        matlab.ui.control.UIAxes
        photo2_2                        matlab.ui.control.UIAxes
        photo3_2                        matlab.ui.control.UIAxes
        photo4_2                        matlab.ui.control.UIAxes
        photo5_2                        matlab.ui.control.UIAxes
        photo6_2                        matlab.ui.control.UIAxes
        photo7_2                        matlab.ui.control.UIAxes
        photo8_2                        matlab.ui.control.UIAxes
        photo9_2                        matlab.ui.control.UIAxes
        photo10_2                       matlab.ui.control.UIAxes
        dates1_2                        matlab.ui.control.Table
        dates2_2                        matlab.ui.control.Table
        dates3_2                        matlab.ui.control.Table
        dates4_2                        matlab.ui.control.Table
        dates5_2                        matlab.ui.control.Table
        dates6_2                        matlab.ui.control.Table
        dates7_2                        matlab.ui.control.Table
        dates8_2                        matlab.ui.control.Table
        dates9_2                        matlab.ui.control.Table
        dates10_2                       matlab.ui.control.Table
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T   % Table for Image 
        T1  % Table for Folder    HU
        T2  % Table for Distance  HU 
        T3  % Table for image #1  HU
        T4  % Table for image #2  HU
        T5  % Table for image #3  HU
        T6  % Table for image #4  HU
        T7  % Table for image #5  HU
        T8  % Table for image #6  HU
        T9  % Table for image #7  HU
        T10 % Table for image #8  HU
        T11 % Table for image #9  HU
        T12 % Table for image #10 HU
        
        T13 % Table for Zernike
        T14 % Table for Distance  Zernike
        T15 % Table for image #1  Zernike
        T16 % Table for image #2  Zernike
        T17 % Table for image #3  Zernike
        T18 % Table for image #4  Zernike
        T19 % Table for image #5  Zernike
        T20 % Table for image #6  Zernike
        T21 % Table for image #7  Zernike
        T22 % Table for image #8  Zernike
        T23 % Table for image #9  Zernike
        T24 % Table for image #10 Zernike
        
        T25 % Table for Zernike Similarity

    end
    
    methods (Access = private)
        
        % -------------------------- funzione Hu_Moments --------------------------
        function phi = Hu_Moments(~, F)
            %INVMOMENTS Compute invariant moments of image.
            %   PHI = INVMOMENTS(F) computes the moment invariants of the image
            %   F. PHI is a seven-element row vector containing the moment
            %   invariants as defined in equations (11.3-17) through (11.3-23) of
            %   Gonzalez and Woods, Digital Image Processing, 2nd Ed.
            %
            %   F must be a 2-D, real, nonsparse, numeric or logical matrix.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/11/21 14:39:19 $
            
            if (~ismatrix(F)) || issparse(F) || ~isreal(F) || ~(isnumeric(F) || islogical(F))
               error(['F must be a 2-D, real, nonsparse, numeric or logical ' 'matrix.']);                       
            end
            
            F = double(F);
            phi = compute_phi(compute_eta(compute_m(F)));            
                
           
            % -------------------------- funzione compute_m --------------------------
            function m = compute_m(F)
                [M, N] = size(F);
                [x, y] = meshgrid(1:N, 1:M);
                  
                % Turn x, y, and F into column vectors to make the summations a bit
                % easier to compute in the following.
                x = x(:);
                y = y(:);
                F = F(:);
                  
                % DIP equation (11.3-12)
                m.m00 = sum(F);
                % Protect against divide-by-zero warnings.
                if (m.m00 == 0)
                   m.m00 = eps;
                end
                % The other central moments:  
                m.m10 = sum(x .* F);
                m.m01 = sum(y .* F);
                m.m11 = sum(x .* y .* F);
                m.m20 = sum(x.^2 .* F);
                m.m02 = sum(y.^2 .* F);
                m.m30 = sum(x.^3 .* F);
                m.m03 = sum(y.^3 .* F);
                m.m12 = sum(x .* y.^2 .* F);
                m.m21 = sum(x.^2 .* y .* F);            
            end
            
            % -------------------------- funzione compute_eta --------------------------
            function e = compute_eta(m)
    
                % DIP equations (11.3-14) through (11.3-16).
                
                xbar = m.m10 / m.m00;
                ybar = m.m01 / m.m00;
                
                e.eta11 = (m.m11 - ybar*m.m10) / m.m00^2;
                e.eta20 = (m.m20 - xbar*m.m10) / m.m00^2;
                e.eta02 = (m.m02 - ybar*m.m01) / m.m00^2;
                e.eta30 = (m.m30 - 3 * xbar * m.m20 + 2 * xbar^2 * m.m10) / m.m00^2.5;
                e.eta03 = (m.m03 - 3 * ybar * m.m02 + 2 * ybar^2 * m.m01) / m.m00^2.5;
                e.eta21 = (m.m21 - 2 * xbar * m.m11 - ybar * m.m20 + 2 * xbar^2 * m.m01) / m.m00^2.5;                       
                e.eta12 = (m.m12 - 2 * ybar * m.m11 - xbar * m.m02 + 2 * ybar^2 * m.m10) / m.m00^2.5;                               
            end
            
            % -------------------------- funzione compute_phi --------------------------
            function phi = compute_phi(e)
                % DIP equations (11.3-17) through (11.3-23).
                
                phi(1) = e.eta20 + e.eta02;
                phi(2) = (e.eta20 - e.eta02)^2 + 4*e.eta11^2;
                phi(3) = (e.eta30 - 3*e.eta12)^2 + (3*e.eta21 - e.eta03)^2;
                phi(4) = (e.eta30 + e.eta12)^2 + (e.eta21 + e.eta03)^2;
                phi(5) = (e.eta30 - 3*e.eta12) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                phi(6) = (e.eta20 - e.eta02) * ( (e.eta30 + e.eta12)^2 - ...
                                                 (e.eta21 + e.eta03)^2 ) + ...
                         4 * e.eta11 * (e.eta30 + e.eta12) * (e.eta21 + e.eta03);
                phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );           
            end
        end    
        
        % ---------------- Funzione per trasformare la figura in 2D ----------------
        %function grayscale(app)
        %    global image1
        %    image1 = rgb2gray(image1);
        %end
        
        
        % ---------------- Funzione per ricercare l'immagine nella cartella ----------------
        % funzione creata al solo scopo di non dover ripetere la ricerca
        % all'interno della cartella per ogni immagine. Viene utilizzata
        % quando il sistema deve ricercare le 10 immagini con i valori di
        % similarità più vicini a quella scelta dall'utente
        
        function search(~, folder, photo, graph)
            global path 
            
            for k = 1:length(folder) 
                baseFileName = folder(k).name;
                fullFileName = fullfile(path, baseFileName);
                pick = imread(fullFileName);
                
                pick = uint8(pick); %conversione dell'immagine in 8bit

                
                if (strcmp(baseFileName, photo) == 1)
                    imshow(pick, 'Parent', graph); % mostro a video l'immagine al posto del piano
                end
            end            
        end
        
        % -------------------------- Funzione Zernike_Moments --------------------------
        function [zernike_mom] = Zernike_Moments(~,f, ord, repet)
            % Function to compute Zernike moments up to order ord.
            %       f = Input image M x N matrix (can be grayscale or color)
            %       ord = The maximum order (scalar)
            %       rep = repetition  number
            %       zernike_mom = Vector containing all the moments up to order ord
            %   
            % EXAMPLE
            %       
            %   RGBfile = imgetfile();
            %   RGB = imread(RGBfile);
            %   if size(RGB, 3)==1
            %       gray = RGB;
            %   else
            %       gray = rgb2gray(RGB);
            %   end
            %   ord = 4;
            %   repet = 2;
            %   mom = Zernike_Moments(gray, ord, repet);
            %   mom2 = Zernike_Moments(imrotate(gray, 90), ord, repet);
            %   mom3 = Zernike_Moments(imresize(gray, 0.5), ord, repet);
            %   MOM = [mom;mom2;mom3];
            if size(f,3)==3  
                f = rgb2gray(f); 
            end

            f = double(f);
            zernike_mom = [];

            for i=0:ord
                for j=0:repet
                    if i>=j && mod((i+j),2)==0
                        % Computation of Zernike moment with order i and repetition j 
                        [~, AOH, ~] = Zernikmoment_MN(f,i,j); 
                        zernike_mom = [zernike_mom AOH];
                    end
                end
            end  
        
            % -------------------------- Funzione Zernikemoment_MN --------------------------
            function [Z, A, Phi] = Zernikmoment_MN(f,n,m)
                % Function to find the Zernike moments for an M x N grayscale image
                %       f = Input image M x N matrix 
                %       n = The order of Zernike moment (scalar)
                %       m = The repetition number of Zernike moment (scalar)
                %       Z = Complex Zernike moment 
                %       A = Amplitude of the moment
                %       Phi = phase (angle) of the moment (in degrees)
                %
                [M, N]  = size(f);
                y = 1:M; x = 1:N;  % x row, y col

                [X,Y] = meshgrid(x,y);
                R = sqrt( (2.*X-N-1).^2/N^2 + (2.*Y-M-1).^2/M^2 );
                Theta = atan2((M-1-2.*Y+2)/M,(2.*X-N+1-2)/N);

                R = (R<=1).*R;
                Rad = radialpoly(R,n,m);    % get the radial polynomial
                Product = f.*Rad.*exp(-1i*m*Theta);
                Z = sum(Product(:));        % calculate the moments
 
                cnt = nnz(R)+1;             % count the number of pixels inside the unit circle
                Z = (n+1)*Z/cnt;            % normalize the amplitude of moments
                A = abs(Z);                 % calculate the amplitude of the moment
                Phi = angle(Z)*180/pi;      % calculate the phase of the moment (in degrees)
            end
            
            % -------------------------- Funzione radialpoly --------------------------
            function rad = radialpoly(r,n,m)
                % Function to compute Zernike Polynomials:
                %       r = radius
                %       n = the order of Zernike polynomial
                %       m = the repetition of Zernike moment
                rad = zeros(size(r));                     % Initilization
                for s = 0:(n-abs(m))/2
                    c = (-1)^s*factorial(n-s)/(factorial(s)*factorial((n+abs(m))/2-s)*factorial((n-abs(m))/2-s));
                rad = rad + c*r.^(n-2*s);
                
                end
            end
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton, 
        % LoadFolderButton_2
        function LoadFolderButtonPushed(app, event)
            % variabili globali 
            global path            
            
            path = uigetdir;                       % 'uigetdir' recupera il path in base alla cartella selezionata dall'utente
            
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
            
            % inserito lo stesso controllo presente nell'inserimento
            % dell'immagine, in modo che non venga generato un errore nel
            % caso in cui l'utente dovesse premere per sbaglio sul tasto
            % per annullare la selezione di una cartella
            
            if(ischar(path))
                app.DirectoryPathLabel.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 
                app.DirectoryPathLabel_2.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 


                a = dir(path);                                      % recupero il contenuto della cartella attraverso il path e la funzione 'dir'
                imageVector = {a(:).name}';                         % recupero tutti i nomi dei vari file presenti nella cartella e li salvo in un array
                imageVector(ismember(imageVector,{'.','..'})) = []; % rimuovo eventuali 'punti' o 'doppi punti' che non sono necessari

                app.DirectoryContents.Data = imageVector;           % mostro il contenuto della cartella nella tabella "Directory Contents"
                app.DirectoryContents_2.Data = imageVector;         % mostro il contenuto della cartella nella tabella "Directory Contents"

            end                                         
        end

        % Button pushed function: AlgorithmHUButton
        function AlgorithmHUButtonPushed(app, event)
            % variabili globali         
            global path
            global nameImage
            global immagini
            global imageFeatures
            global folderFeatures
                      
            folderFeatures = [];
            imageFeatures = [];
            
            % ciclo per scorrere il vettore in cui sono contenute le
            % immagini, applicare la funzione e salvare ogni valore in una
            % riga della seconda tabella
            
            pattern = fullfile(path, '*.tiff'); % -------- da automatizzare --------
            immagini = dir(pattern);
            
            app.UIFigure.Visible = 'off';          
            app.UIFigure.Visible = 'on'; 
            
            for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                fullFileName = fullfile(path, baseFileName);
                image = imread(fullFileName);
                %grayscale(app);
                
                nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                folderFeatures(k, :) = app.Hu_Moments(image); %salvo in un vettore tutti i valori delle feautures                    
                               
            end
            folderFeatures = normalize(folderFeatures, 'range', [0,1]);
            
            for k = 1:length(immagini)
                nameImageFolder = immagini(k).name;
                if (strcmp(nameImage, nameImageFolder))
                    imageFeatures = folderFeatures(k,:);     
                    disp(imageFeatures);
                end                
            end
           
            
            % ---------------- Tabella in cui vengono salvate le features della cartella ----------------
            app.T1 = table(folderFeatures, 'RowNames', nomeRiga);                
            app.Features_Dataset.Data = app.T1{:,:}; % salvo i valori ottenuti all'interno della tabella
            app.Features_Dataset.RowName = app.T1.Properties.RowNames;        
             
        end

        % Button pushed function: LoadImageButton, LoadImageButton_2
        function LoadImageButtonPushed(app, event)
            global image1     
            global nameImage
                   
            filterImage = {'*.tiff;*.jpg;*.png;*.gif;*.tif;*.bmp', 'All Image Files'}; %imposto il filtro per la scelta delle immagini (in base al tipo di file)
            
            [file, path] =  uigetfile(filterImage); % l'utente sceglie un'immagine sulla base del filtro presente
            
            % con questo controllo faccio in modo che l'applicazione non
            % vada avanti fino a quando l'utente non inserisce un'immagine,
            % evitando il crash nel caso in cui decida di provare a uscire
            % dalla finestra di dialogo che viene generata
            if(ischar(path))
                nameImage = file;                   % mi prendo il nome dell'immagine scelta per effettuare tutti i confronti
                fullpathname = strcat(path, file);
                image1 = imread(fullpathname);
            end
            app.photoPicked.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente
            app.photoPicked_2.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente
            app.photoPicked_3.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente
            app.photoPicked_4.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente

            
            img8 = uint8(image1); %conversione dell'immagine in 8bit
            
            imshow(img8, 'Parent', app.UIAxes); % mostro a video l'immagine al posto del piano
            imshow(img8, 'Parent', app.UIAxes_2); % mostro a video l'immagine al posto del piano

        end

        % Button pushed function: EuclideanDistanceButton
        function EuclideanDistanceButtonPushed(app, event)
            global immagini
            global imageFeatures
            global folderFeatures
            
             for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                

                distance(k, :) = pdist2(folderFeatures(k,:), imageFeatures(1,:), 'euclidean');
             end
             
              % ---------------- Tabella in cui vengono salvati i valori di dissimilarità riordinati ----------------
            app.T2 = table(distance, 'RowNames', nomeRiga);           
            app.T2 = sortrows(app.T2);                 % riordinamento delle righe della tabella con le distanze
            app.Euclidean_Results.Data = app.T2{:,:};  % scrittura dati all'interno della tabella interfaccia (valori dissimilarità)
            app.Euclidean_Results.RowName = app.T2.Properties.RowNames; % scrittura nomi delle righe per avere il corrispettivo dell'immagine
            
           
            % ---------------- Tabella immagine 1 ----------------
            app.T3 = table(app.T2{2,:}); %creazione tabella
            app.dates1.Data = app.T3{:,:}; %assegnazione valori
            app.dates1.RowName = app.T2.Properties.RowNames(2); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(2), app.photo1); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 2 ----------------
            app.T4 = table(app.T2{3,:}); %creazione tabella
            app.dates2.Data = app.T4{:,:}; %assegnazione valori
            app.dates2.RowName = app.T2.Properties.RowNames(3); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(3), app.photo2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 3 ----------------
            app.T5 = table(app.T2{4,:}); %creazione tabella
            app.dates3.Data = app.T5{:,:}; %assegnazione valori
            app.dates3.RowName = app.T2.Properties.RowNames(4); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(4), app.photo3); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 4 ----------------
            app.T6 = table(app.T2{5,:}); %creazione tabella
            app.dates4.Data = app.T6{:,:}; %assegnazione valori
            app.dates4.RowName = app.T2.Properties.RowNames(5); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(5), app.photo4); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 5 ----------------
            app.T7 = table(app.T2{6,:}); %creazione tabella
            app.dates5.Data = app.T7{:,:}; %assegnazione valori
            app.dates5.RowName = app.T2.Properties.RowNames(6); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(6), app.photo5); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 6 ----------------
            app.T8 = table(app.T2{7,:}); %creazione tabella
            app.dates6.Data = app.T8{:,:}; %assegnazione valori
            app.dates6.RowName = app.T2.Properties.RowNames(7); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(7), app.photo6); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 7 ----------------
            app.T9 = table(app.T2{8,:}); %creazione tabella
            app.dates7.Data = app.T9{:,:}; %assegnazione valori
            app.dates7.RowName = app.T2.Properties.RowNames(8); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(8), app.photo7); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 8 ----------------
            app.T10 = table(app.T2{9,:}); %creazione tabella
            app.dates8.Data = app.T10{:,:}; %assegnazione valori
            app.dates8.RowName = app.T2.Properties.RowNames(9); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(9), app.photo8); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 9 ----------------
            app.T11 = table(app.T2{10,:}); %creazione tabella
            app.dates9.Data = app.T11{:,:}; %assegnazione valori
            app.dates9.RowName = app.T2.Properties.RowNames(10); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(10), app.photo9); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 10 ----------------
            app.T12 = table(app.T2{11,:}); %creazione tabella
            app.dates10.Data = app.T12{:,:}; %assegnazione valori
            app.dates10.RowName = app.T2.Properties.RowNames(11); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(11), app.photo10); %ricerca immagine e visualizzazione interfaccia
             
             
        end

        % Button pushed function: AlgorithmZernikeButton
        function AlgorithmZernikeButtonPushed(app, event)
            % variabili globali         
            global path            %path cartella
            global nameImage       %nomeImmagine
            global immagini        %contenuto cartella
            global imageFeatures_Z   %vettore features dell'immagine scelta
            global folderFeatures_Z  %vettore features della cartella     
            
            ord = app.GetOrderEditField.Value;
            rep = app.GetRepEditField.Value;              
            
            if rep < ord
                          
                folderFeatures_Z = [];
                imageFeatures_Z = [];
            
                % ciclo per scorrere il vettore in cui sono contenute le
                % immagini, applicare la funzione e salvare ogni valore in una
                % riga della seconda tabella
            
                pattern = fullfile(path, '*.tiff'); % -------- da automatizzare --------
                immagini = dir(pattern);
            
                app.UIFigure.Visible = 'off';          
                app.UIFigure.Visible = 'on'; 
                
            for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                fullFileName = fullfile(path, baseFileName);
                image = imread(fullFileName);
                %grayscale(app);
                
                nomeRiga_Z(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                folderFeatures_Z(k, :) = app.Zernike_Moments(image, ord, rep); %salvo in un vettore tutti i valori delle feautures                    
                               
            end
            folderFeatures_Z = normalize(folderFeatures_Z, 'range', [0,1]);
            
            for k = 1:length(immagini)
                nameImageFolder = immagini(k).name;
                if (strcmp(nameImage, nameImageFolder))
                    imageFeatures_Z = folderFeatures_Z(k,:);     
                end                
            end


            % Conteggio delle colonne per rendere tutto dinamico 
            %number_columns = size(folderFeatures_Z(1,:),2);
            %disp(number_columns);
            %value = 'Valore'; 
            %for i = 1:number_columns
            %    nomeColonna_Z(i, :) = {[value '_' num2str(i, '%d')]};
            %end
            %nomeColonna_Z = array2table(nomeColonna_Z);
            %disp(nomeColonna_Z);

            
            % ---------------- Tabella in cui vengono salvate le features della cartella ----------------
            app.T13 = table(folderFeatures_Z, 'RowNames', nomeRiga_Z);  
            
            app.Features_Dataset_2.Data = app.T13{:,:}; % salvo i valori ottenuti all'interno della tabella
            
           
            app.Features_Dataset_2.RowName = app.T13.Properties.RowNames;    
            
            else
                f = errordlg('Error! The value of ORD must be "greater than"/"equal to" the REP value');
                uiwait(f);            
            end


        end

        % Button pushed function: EuclideanDistanceZernikeButton
        function EuclideanDistanceZernikeButtonPushed(app, event)
            global immagini
            global imageFeatures_Z
            global folderFeatures_Z
            global distance_Z
            
             for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                
                distance_Z(k, :) = pdist2(folderFeatures_Z(k,:), imageFeatures_Z(1,:), 'euclidean');
             end
             
            % ---------------- Tabella in cui vengono salvati i valori di dissimilarità riordinati ----------------
            app.T14 = table(distance_Z, 'RowNames', nomeRiga);           
            app.T14 = sortrows(app.T14);                 % riordinamento delle righe della tabella con le distanze
            app.Euclidean_Results_2.Data = app.T14{:,:};  % scrittura dati all'interno della tabella interfaccia (valori dissimilarità)
            app.Euclidean_Results_2.RowName = app.T14.Properties.RowNames; % scrittura nomi delle righe per avere il corrispettivo dell'immagine
            
            
            % ---------------- Tabella immagine 1 ----------------
            app.T15 = table(app.T14{2,:}); %creazione tabella
            app.dates1_2.Data = app.T15{:,:}; %assegnazione valori
            app.dates1_2.RowName = app.T14.Properties.RowNames(2); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(2), app.photo1_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 2 ----------------
            app.T16 = table(app.T14{3,:}); %creazione tabella
            app.dates2_2.Data = app.T16{:,:}; %assegnazione valori
            app.dates2_2.RowName = app.T14.Properties.RowNames(3); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(3), app.photo2_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 3 ----------------
            app.T17 = table(app.T14{4,:}); %creazione tabella
            app.dates3_2.Data = app.T17{:,:}; %assegnazione valori
            app.dates3_2.RowName = app.T14.Properties.RowNames(4); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(4), app.photo3_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 4 ----------------
            app.T18 = table(app.T14{5,:}); %creazione tabella
            app.dates4_2.Data = app.T18{:,:}; %assegnazione valori
            app.dates4_2.RowName = app.T14.Properties.RowNames(5); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(5), app.photo4_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 5 ----------------
            app.T19 = table(app.T14{6,:}); %creazione tabella
            app.dates5_2.Data = app.T19{:,:}; %assegnazione valori
            app.dates5_2.RowName = app.T14.Properties.RowNames(6); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(6), app.photo5_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 6 ----------------
            app.T20 = table(app.T14{7,:}); %creazione tabella
            app.dates6_2.Data = app.T20{:,:}; %assegnazione valori
            app.dates6_2.RowName = app.T14.Properties.RowNames(7); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(7), app.photo6_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 7 ----------------
            app.T21 = table(app.T14{8,:}); %creazione tabella
            app.dates7_2.Data = app.T21{:,:}; %assegnazione valori
            app.dates7_2.RowName = app.T14.Properties.RowNames(8); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(8), app.photo7_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 8 ----------------
            app.T22 = table(app.T14{9,:}); %creazione tabella
            app.dates8_2.Data = app.T22{:,:}; %assegnazione valori
            app.dates8_2.RowName = app.T14.Properties.RowNames(9); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(9), app.photo8_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 9 ----------------
            app.T23 = table(app.T14{10,:}); %creazione tabella
            app.dates9_2.Data = app.T23{:,:}; %assegnazione valori
            app.dates9_2.RowName = app.T14.Properties.RowNames(10); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(10), app.photo9_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 10 ----------------
            app.T24 = table(app.T14{11,:}); %creazione tabella
            app.dates10_2.Data = app.T24{:,:}; %assegnazione valori
            app.dates10_2.RowName = app.T14.Properties.RowNames(11); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(11), app.photo10_2); %ricerca immagine e visualizzazione interfaccia
        end

        % Button pushed function: SimilarityButton
        function SimilarityButtonPushed(app, event)
            global immagini
            global imageFeatures_Z
            global folderFeatures_Z
            
            similarity = 0;
            
            for i = 1:length(immagini)
               baseFileName = immagini(i).name;
               nomeRiga(i, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini     
               
               similarity = similarity + abs((folderFeatures_Z(i,:) - imageFeatures_Z(1,:))/(1 + folderFeatures_Z(i,:) - imageFeatures_Z(1,:)));
                
               line(i, :) = similarity;
            end
            
            % ---------------- Tabella in cui vengono salvati i valori di dissimilarità riordinati ----------------
            app.T25 = table(line, 'RowNames', nomeRiga);           
            %app.T25 = sortrows(app.T25);                 % riordinamento delle righe della tabella con le distanze
            app.Similarity_Results.Data = app.T25{:,:};  % scrittura dati all'interno della tabella interfaccia (valori dissimilarità)
            app.Similarity_Results.RowName = app.T25.Properties.RowNames; % scrittura nomi delle righe per avere il corrispettivo dell'immagine
            
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HU_ApplicationTab = uitab(app.TabGroup);
            app.HU_ApplicationTab.Title = 'HU_Application';
            app.LoadFolderButton = uibutton(app.HU_ApplicationTab, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Icon = 'Icon_Folder.png';
            app.LoadFolderButton.Text = {'   Load Folder'; ''};
            app.DirectoryPathLabel = uilabel(app.HU_ApplicationTab);
            app.DirectoryPathLabel.Text = '   Directory Path';
            app.DirectoryContents = uitable(app.HU_ApplicationTab);
            app.DirectoryContents.ColumnName = {'Directory Content'};
            app.DirectoryContents.RowName = {};
            app.AlgorithmHUButton = uibutton(app.HU_ApplicationTab, 'push');
            app.AlgorithmHUButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmHUButtonPushed, true);
            app.AlgorithmHUButton.Text = {'Algorithm HU'; ''};
            app.Features_Dataset = uitable(app.HU_ApplicationTab);
            app.Features_Dataset.ColumnName = {'Valore 1'; 'Valore 2'; 'Valore 3'; 'Valore 4'; 'Valore 5'; 'Valore 6'; 'Valore 7'};
            app.Features_Dataset.RowName = {};
            app.Euclidean_Results = uitable(app.HU_ApplicationTab);
            app.Euclidean_Results.ColumnName = {'Euclidean Distance'};
            app.Euclidean_Results.RowName = {};
            app.LoadImageButton = uibutton(app.HU_ApplicationTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'Icon_Folder.png';
            app.LoadImageButton.Text = {'   Load Image'; ''};
            app.photoPicked_2 = uilabel(app.HU_ApplicationTab);
            app.photoPicked_2.Text = {'Photo Picked'; ''};
            app.EuclideanDistanceButton = uibutton(app.HU_ApplicationTab, 'push');
            app.EuclideanDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @EuclideanDistanceButtonPushed, true);
            app.EuclideanDistanceButton.Text = {'Euclidean Distance'; ''};
            app.Zernike_ApplicationTab = uitab(app.TabGroup);
            app.Zernike_ApplicationTab.Title = 'Zernike_Application';
            app.LoadFolderButton_2 = uibutton(app.Zernike_ApplicationTab, 'push');
            app.LoadFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton_2.Icon = 'Icon_Folder.png';
            app.LoadFolderButton_2.Text = {'   Load Folder'; ''};
            app.DirectoryPathLabel_2 = uilabel(app.Zernike_ApplicationTab);
            app.DirectoryPathLabel_2.Text = '   Directory Path';
            app.DirectoryContents_2 = uitable(app.Zernike_ApplicationTab);
            app.DirectoryContents_2.ColumnName = {'Directory Content'};
            app.DirectoryContents_2.RowName = {};
            app.AlgorithmZernikeButton = uibutton(app.Zernike_ApplicationTab, 'push');
            app.AlgorithmZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmZernikeButtonPushed, true);
            app.AlgorithmZernikeButton.Text = 'Algorithm Zernike';
            app.Features_Dataset_2 = uitable(app.Zernike_ApplicationTab);
            app.Features_Dataset_2.ColumnName = {''};
            app.Features_Dataset_2.RowName = {};
            app.Euclidean_Results_2 = uitable(app.Zernike_ApplicationTab);
            app.Euclidean_Results_2.ColumnName = {'Euclidean Distance'};
            app.Euclidean_Results_2.RowName = {};
            app.LoadImageButton_2 = uibutton(app.Zernike_ApplicationTab, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton_2.Icon = 'Icon_Folder.png';
            app.LoadImageButton_2.Text = {'   Load Image'; ''};
            app.photoPicked_3 = uilabel(app.Zernike_ApplicationTab);
            app.photoPicked_3.Text = {'Photo Picked'; ''};
            app.EuclideanDistanceZernikeButton = uibutton(app.Zernike_ApplicationTab, 'push');
            app.EuclideanDistanceZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @EuclideanDistanceZernikeButtonPushed, true);
            app.EuclideanDistanceZernikeButton.Text = {'Euclidean '; 'Distance '; 'Zernike'; ''; ''};
            app.GetOrderEditFieldLabel = uilabel(app.Zernike_ApplicationTab);
            app.GetOrderEditFieldLabel.Text = 'Get Order';
            app.GetOrderEditField = uieditfield(app.Zernike_ApplicationTab, 'numeric');
            app.GetRepEditFieldLabel = uilabel(app.Zernike_ApplicationTab);
            app.GetRepEditFieldLabel.Text = {'Get Rep'; ''};
            app.GetRepEditField = uieditfield(app.Zernike_ApplicationTab, 'numeric');
            app.SimilarityButton = uibutton(app.Zernike_ApplicationTab, 'push');
            app.SimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarityButtonPushed, true);
            app.SimilarityButton.Text = 'Similarity ';
            app.Similarity_Results = uitable(app.Zernike_ApplicationTab);
            app.Similarity_Results.ColumnName = {'Similarity Measure'};
            app.Similarity_Results.RowName = {};
            app.HU_ResultsTab = uitab(app.TabGroup);
            app.HU_ResultsTab.Title = 'HU_Results';
            app.ImageforComparisonwithHULabel = uilabel(app.HU_ResultsTab);
            app.ImageforComparisonwithHULabel.Text = {'Image for Comparison'; '            with HU'};
            app.photoPicked = uilabel(app.HU_ResultsTab);
            app.photoPicked.Text = {'Photo Picked'; ''};
            app.photo1 = uiaxes(app.HU_ResultsTab);
            title(app.photo1, '')
            xlabel(app.photo1, '')
            ylabel(app.photo1, '')
            app.photo1.XTick = [];
            app.photo1.XTickLabel = '';
            app.photo1.YTick = [];
            app.photo2 = uiaxes(app.HU_ResultsTab);
            title(app.photo2, '')
            xlabel(app.photo2, '')
            ylabel(app.photo2, '')
            app.photo2.XTick = [];
            app.photo2.XTickLabel = '';
            app.photo2.YTick = [];
            app.photo3 = uiaxes(app.HU_ResultsTab);
            title(app.photo3, '')
            xlabel(app.photo3, '')
            ylabel(app.photo3, '')
            app.photo3.XTick = [];
            app.photo3.XTickLabel = '';
            app.photo3.YTick = [];
            app.photo4 = uiaxes(app.HU_ResultsTab);
            title(app.photo4, '')
            xlabel(app.photo4, '')
            ylabel(app.photo4, '')
            app.photo4.XTick = [];
            app.photo4.XTickLabel = '';
            app.photo4.YTick = [];
            app.photo5 = uiaxes(app.HU_ResultsTab);
            title(app.photo5, '')
            xlabel(app.photo5, '')
            ylabel(app.photo5, '')
            app.photo5.XTick = [];
            app.photo5.XTickLabel = '';
            app.photo5.YTick = [];
            app.photo6 = uiaxes(app.HU_ResultsTab);
            title(app.photo6, '')
            xlabel(app.photo6, '')
            ylabel(app.photo6, '')
            app.photo6.XTick = [];
            app.photo6.XTickLabel = '';
            app.photo6.YTick = [];
            app.photo7 = uiaxes(app.HU_ResultsTab);
            title(app.photo7, '')
            xlabel(app.photo7, '')
            ylabel(app.photo7, '')
            app.photo7.XTick = [];
            app.photo7.XTickLabel = '';
            app.photo7.YTick = [];
            app.photo8 = uiaxes(app.HU_ResultsTab);
            title(app.photo8, '')
            xlabel(app.photo8, '')
            ylabel(app.photo8, '')
            app.photo8.XTick = [];
            app.photo8.XTickLabel = '';
            app.photo8.YTick = [];
            app.photo9 = uiaxes(app.HU_ResultsTab);
            title(app.photo9, '')
            xlabel(app.photo9, '')
            ylabel(app.photo9, '')
            app.photo9.XTick = [];
            app.photo9.XTickLabel = '';
            app.photo9.YTick = [];
            app.photo10 = uiaxes(app.HU_ResultsTab);
            title(app.photo10, '')
            xlabel(app.photo10, '')
            ylabel(app.photo10, '')
            app.photo10.XTick = [];
            app.photo10.XTickLabel = '';
            app.photo10.YTick = [];
            app.dates1 = uitable(app.HU_ResultsTab);
            app.dates1.ColumnName = {'Dissimilarity'};
            app.dates1.RowName = {};
            app.dates2 = uitable(app.HU_ResultsTab);
            app.dates2.ColumnName = {'Dissimilarity'};
            app.dates2.RowName = {};
            app.dates3 = uitable(app.HU_ResultsTab);
            app.dates3.ColumnName = {'Dissimilarity'};
            app.dates3.RowName = {};
            app.dates4 = uitable(app.HU_ResultsTab);
            app.dates4.ColumnName = {'Dissimilarity'};
            app.dates4.RowName = {};
            app.dates5 = uitable(app.HU_ResultsTab);
            app.dates5.ColumnName = {'Dissimilarity'};
            app.dates5.RowName = {};
            app.dates6 = uitable(app.HU_ResultsTab);
            app.dates6.ColumnName = {'Dissimilarity'};
            app.dates6.RowName = {};
            app.dates7 = uitable(app.HU_ResultsTab);
            app.dates7.ColumnName = {'Dissimilarity'};
            app.dates7.RowName = {};
            app.dates8 = uitable(app.HU_ResultsTab);
            app.dates8.ColumnName = {'Dissimilarity'};
            app.dates8.RowName = {};
            app.dates9 = uitable(app.HU_ResultsTab);
            app.dates9.ColumnName = {'Dissimilarity'};
            app.dates9.RowName = {};
            app.dates10 = uitable(app.HU_ResultsTab);
            app.dates10.ColumnName = {'Dissimilarity'};
            app.dates10.RowName = {};
            app.UIAxes = uiaxes(app.HU_ResultsTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Zernike_ResultsTab = uitab(app.TabGroup);
            app.Zernike_ResultsTab.Title = 'Zernike_Results';
            app.ImageforComparisonwithZernikeLabel = uilabel(app.Zernike_ResultsTab);
            app.ImageforComparisonwithZernikeLabel.Text = {'Image for Comparison'; '         with Zernike'; ''};
            app.photoPicked_4 = uilabel(app.Zernike_ResultsTab);
            app.photoPicked_4.Text = {'Photo Picked'; ''};
            app.photo1_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo1_2, '')
            xlabel(app.photo1_2, '')
            ylabel(app.photo1_2, '')
            app.photo1_2.XTick = [];
            app.photo1_2.XTickLabel = '';
            app.photo1_2.YTick = [];
            app.photo2_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo2_2, '')
            xlabel(app.photo2_2, '')
            ylabel(app.photo2_2, '')
            app.photo2_2.XTick = [];
            app.photo2_2.XTickLabel = '';
            app.photo2_2.YTick = [];
            app.photo3_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo3_2, '')
            xlabel(app.photo3_2, '')
            ylabel(app.photo3_2, '')
            app.photo3_2.XTick = [];
            app.photo3_2.XTickLabel = '';
            app.photo3_2.YTick = [];
            app.photo4_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo4_2, '')
            xlabel(app.photo4_2, '')
            ylabel(app.photo4_2, '')
            app.photo4_2.XTick = [];
            app.photo4_2.XTickLabel = '';
            app.photo4_2.YTick = [];
            app.photo5_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo5_2, '')
            xlabel(app.photo5_2, '')
            ylabel(app.photo5_2, '')
            app.photo5_2.XTick = [];
            app.photo5_2.XTickLabel = '';
            app.photo5_2.YTick = [];
            app.photo6_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo6_2, '')
            xlabel(app.photo6_2, '')
            ylabel(app.photo6_2, '')
            app.photo6_2.XTick = [];
            app.photo6_2.XTickLabel = '';
            app.photo6_2.YTick = [];
            app.photo7_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo7_2, '')
            xlabel(app.photo7_2, '')
            ylabel(app.photo7_2, '')
            app.photo7_2.XTick = [];
            app.photo7_2.XTickLabel = '';
            app.photo7_2.YTick = [];
            app.photo8_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo8_2, '')
            xlabel(app.photo8_2, '')
            ylabel(app.photo8_2, '')
            app.photo8_2.XTick = [];
            app.photo8_2.XTickLabel = '';
            app.photo8_2.YTick = [];
            app.photo9_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo9_2, '')
            xlabel(app.photo9_2, '')
            ylabel(app.photo9_2, '')
            app.photo9_2.XTick = [];
            app.photo9_2.XTickLabel = '';
            app.photo9_2.YTick = [];
            app.photo10_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.photo10_2, '')
            xlabel(app.photo10_2, '')
            ylabel(app.photo10_2, '')
            app.photo10_2.XTick = [];
            app.photo10_2.XTickLabel = '';
            app.photo10_2.YTick = [];
            app.dates1_2 = uitable(app.Zernike_ResultsTab);
            app.dates1_2.ColumnName = {'Dissimilarity'};
            app.dates1_2.RowName = {};
            app.dates2_2 = uitable(app.Zernike_ResultsTab);
            app.dates2_2.ColumnName = {'Dissimilarity'};
            app.dates2_2.RowName = {};
            app.dates3_2 = uitable(app.Zernike_ResultsTab);
            app.dates3_2.ColumnName = {'Dissimilarity'};
            app.dates3_2.RowName = {};
            app.dates4_2 = uitable(app.Zernike_ResultsTab);
            app.dates4_2.ColumnName = {'Dissimilarity'};
            app.dates4_2.RowName = {};
            app.dates5_2 = uitable(app.Zernike_ResultsTab);
            app.dates5_2.ColumnName = {'Dissimilarity'};
            app.dates5_2.RowName = {};
            app.dates6_2 = uitable(app.Zernike_ResultsTab);
            app.dates6_2.ColumnName = {'Dissimilarity'};
            app.dates6_2.RowName = {};
            app.dates7_2 = uitable(app.Zernike_ResultsTab);
            app.dates7_2.ColumnName = {'Dissimilarity'};
            app.dates7_2.RowName = {};
            app.dates8_2 = uitable(app.Zernike_ResultsTab);
            app.dates8_2.ColumnName = {'Dissimilarity'};
            app.dates8_2.RowName = {};
            app.dates9_2 = uitable(app.Zernike_ResultsTab);
            app.dates9_2.ColumnName = {'Dissimilarity'};
            app.dates9_2.RowName = {};
            app.dates10_2 = uitable(app.Zernike_ResultsTab);
            app.dates10_2.ColumnName = {'Dissimilarity'};
            app.dates10_2.RowName = {};
            app.UIAxes_2 = uiaxes(app.Zernike_ResultsTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moments_Applications

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end