classdef Moments_Applications_PROVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ChooseMomentTab              matlab.ui.container.Tab
        LoadFolderButton             matlab.ui.control.Button
        DirectoryPathLabel           matlab.ui.control.Label
        DirectoryContents            matlab.ui.control.Table
        Features_Dataset             matlab.ui.control.Table
        Euclidean_Results            matlab.ui.control.Table
        LoadImageButton              matlab.ui.control.Button
        photoPicked_3                matlab.ui.control.Label
        EuclideanDistanceButton      matlab.ui.control.Button
        GetOrderEditFieldLabel       matlab.ui.control.Label
        GetOrderEditField            matlab.ui.control.NumericEditField
        GetRepEditFieldLabel         matlab.ui.control.Label
        GetRepEditField              matlab.ui.control.NumericEditField
        SimilarityButton             matlab.ui.control.Button
        Similarity_Results           matlab.ui.control.Table
        CalculateButton              matlab.ui.control.Button
        WhatmomentdoyouwanttocalculateButtonGroup  matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        ZernikeButton_2              matlab.ui.control.RadioButton
        LegendreButton_2             matlab.ui.control.RadioButton
        ChebyshevButton              matlab.ui.control.RadioButton
        HUButton_3                   matlab.ui.control.RadioButton
        EuclideanPerformanceButton   matlab.ui.control.Button
        SimilarityPerformanceButton  matlab.ui.control.Button
        Performance_Euclide          matlab.ui.control.Table
        Performance_Similarita       matlab.ui.control.Table
        EuclideanResultsTab_2        matlab.ui.container.Tab
        photo1_2                     matlab.ui.control.UIAxes
        photo2_2                     matlab.ui.control.UIAxes
        photo3_2                     matlab.ui.control.UIAxes
        photo4_2                     matlab.ui.control.UIAxes
        photo5_2                     matlab.ui.control.UIAxes
        photo6_2                     matlab.ui.control.UIAxes
        photo7_2                     matlab.ui.control.UIAxes
        photo8_2                     matlab.ui.control.UIAxes
        photo9_2                     matlab.ui.control.UIAxes
        photo10_2                    matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        photoPicked_4                matlab.ui.control.Label
        dates1_2                     matlab.ui.control.Table
        dates2_2                     matlab.ui.control.Table
        dates3_2                     matlab.ui.control.Table
        dates4_2                     matlab.ui.control.Table
        dates5_2                     matlab.ui.control.Table
        dates6_2                     matlab.ui.control.Table
        dates7_2                     matlab.ui.control.Table
        dates8_2                     matlab.ui.control.Table
        dates9_2                     matlab.ui.control.Table
        dates10_2                    matlab.ui.control.Table
        SimilarityResultsTab         matlab.ui.container.Tab
        photo1                       matlab.ui.control.UIAxes
        photo2                       matlab.ui.control.UIAxes
        photo3                       matlab.ui.control.UIAxes
        photo4                       matlab.ui.control.UIAxes
        photo5                       matlab.ui.control.UIAxes
        photo6                       matlab.ui.control.UIAxes
        photo7                       matlab.ui.control.UIAxes
        photo8                       matlab.ui.control.UIAxes
        photo9                       matlab.ui.control.UIAxes
        photo10                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        photoPicked                  matlab.ui.control.Label
        dates1                       matlab.ui.control.Table
        dates2                       matlab.ui.control.Table
        dates3                       matlab.ui.control.Table
        dates4                       matlab.ui.control.Table
        dates5                       matlab.ui.control.Table
        dates6                       matlab.ui.control.Table
        dates7                       matlab.ui.control.Table
        dates8                       matlab.ui.control.Table
        dates9                       matlab.ui.control.Table
        dates10                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        T   % Table for Image 
        T1  % Table for Folder    
        T2  % Table for Similarity (From Paper)
        T3  % Table for image #1  (Similarity)
        T4  % Table for image #2  (Similarity)
        T5  % Table for image #3  (Similarity)
        T6  % Table for image #4  (Similarity)
        T7  % Table for image #5  (Similarity)
        T8  % Table for image #6  (Similarity)
        T9  % Table for image #7  (Similarity)
        T10 % Table for image #8  (Similarity)
        T11 % Table for image #9  (Similarity)
        T12 % Table for image #10 (Similarity)
        
        T13 % Table for Moment Results
        
        T14 % Table for Euclidean Distance
        T15 % Table for image #1  (Dissimilarity)
        T16 % Table for image #2  (Dissimilarity)
        T17 % Table for image #3  (Dissimilarity)
        T18 % Table for image #4  (Dissimilarity)
        T19 % Table for image #5  (Dissimilarity)
        T20 % Table for image #6  (Dissimilarity)
        T21 % Table for image #7  (Dissimilarity)
        T22 % Table for image #8  (Dissimilarity)
        T23 % Table for image #9  (Dissimilarity)
        T24 % Table for image #10 (Dissimilarity)
        
        TRY  % Tabella ausiliare
        TRY1 % Tabella ausiliare

        RESULT1 % Tabella per i risultati (Euclide)
        RESULT2 % Tabella per i risultati (Similarità)
    end
    
    methods (Access = private)
        
        % -------------------------- funzione Hu_Moments --------------------------
        function phi = Hu_Moments(~, F)
            %INVMOMENTS Compute invariant moments of image.
            %   PHI = INVMOMENTS(F) computes the moment invariants of the image
            %   F. PHI is a seven-element row vector containing the moment
            %   invariants as defined in equations (11.3-17) through (11.3-23) of
            %   Gonzalez and Woods, Digital Image Processing, 2nd Ed.
            %
            %   F must be a 2-D, real, nonsparse, numeric or logical matrix.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/11/21 14:39:19 $
            
            if (~ismatrix(F)) || issparse(F) || ~isreal(F) || ~(isnumeric(F) || islogical(F))
               error(['F must be a 2-D, real, nonsparse, numeric or logical ' 'matrix.']);                       
            end
            
            F = double(F);
            phi = compute_phi(compute_eta(compute_m(F)));            
                
           
            % -------------------------- funzione compute_m --------------------------
            function m = compute_m(F)
                [M, N] = size(F);
                [x, y] = meshgrid(1:N, 1:M);
                  
                % Turn x, y, and F into column vectors to make the summations a bit
                % easier to compute in the following.
                x = x(:);
                y = y(:);
                F = F(:);
                  
                % DIP equation (11.3-12)
                m.m00 = sum(F);
                % Protect against divide-by-zero warnings.
                if (m.m00 == 0)
                   m.m00 = eps;
                end
                % The other central moments:  
                m.m10 = sum(x .* F);
                m.m01 = sum(y .* F);
                m.m11 = sum(x .* y .* F);
                m.m20 = sum(x.^2 .* F);
                m.m02 = sum(y.^2 .* F);
                m.m30 = sum(x.^3 .* F);
                m.m03 = sum(y.^3 .* F);
                m.m12 = sum(x .* y.^2 .* F);
                m.m21 = sum(x.^2 .* y .* F);            
            end
            
            % -------------------------- funzione compute_eta --------------------------
            function e = compute_eta(m)
    
                % DIP equations (11.3-14) through (11.3-16).
                
                xbar = m.m10 / m.m00;
                ybar = m.m01 / m.m00;
                
                e.eta11 = (m.m11 - ybar*m.m10) / m.m00^2;
                e.eta20 = (m.m20 - xbar*m.m10) / m.m00^2;
                e.eta02 = (m.m02 - ybar*m.m01) / m.m00^2;
                e.eta30 = (m.m30 - 3 * xbar * m.m20 + 2 * xbar^2 * m.m10) / m.m00^2.5;
                e.eta03 = (m.m03 - 3 * ybar * m.m02 + 2 * ybar^2 * m.m01) / m.m00^2.5;
                e.eta21 = (m.m21 - 2 * xbar * m.m11 - ybar * m.m20 + 2 * xbar^2 * m.m01) / m.m00^2.5;                       
                e.eta12 = (m.m12 - 2 * ybar * m.m11 - xbar * m.m02 + 2 * ybar^2 * m.m10) / m.m00^2.5;                               
            end
            
            % -------------------------- funzione compute_phi --------------------------
            function phi = compute_phi(e)
                % DIP equations (11.3-17) through (11.3-23).
                
                phi(1) = e.eta20 + e.eta02;
                phi(2) = (e.eta20 - e.eta02)^2 + 4*e.eta11^2;
                phi(3) = (e.eta30 - 3*e.eta12)^2 + (3*e.eta21 - e.eta03)^2;
                phi(4) = (e.eta30 + e.eta12)^2 + (e.eta21 + e.eta03)^2;
                phi(5) = (e.eta30 - 3*e.eta12) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                phi(6) = (e.eta20 - e.eta02) * ( (e.eta30 + e.eta12)^2 - ...
                                                 (e.eta21 + e.eta03)^2 ) + ...
                         4 * e.eta11 * (e.eta30 + e.eta12) * (e.eta21 + e.eta03);
                phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );           
            end
        end    
        
        % ---------------- Funzione per trasformare la figura in 2D ----------------
        %function grayscale(app)
        %    global image1
        %    image1 = rgb2gray(image1);
        %end
        
        
        % ---------------- Funzione per ricercare l'immagine nella cartella ----------------
        % funzione creata al solo scopo di non dover ripetere la ricerca
        % all'interno della cartella per ogni immagine. Viene utilizzata
        % quando il sistema deve ricercare le 10 immagini con i valori di
        % similarità più vicini a quella scelta dall'utente
        
        function search(~, folder, photo, graph)
            global path 
            
            for k = 1:length(folder) 
                baseFileName = folder(k).name;
                fullFileName = fullfile(path, baseFileName);
                pick = imread(fullFileName);
                
                %pick = im2uint8(mat2gray(pick)); %conversione dell'immagine in 8bit

                
                if (strcmp(baseFileName, photo) == 1)
                    imshow(pick, 'Parent', graph); % mostro a video l'immagine al posto del piano
                end
            end            
        end
        
        
        %function calculate_gDB(~ , classeImmagine)
            % determinazione della variabile gDB
         %   global count1
          %  global count2
           % global count3
           % global gDB
            
           % gDB = 0;
            
           % switch (classeImmagine)
           %     case 1
           %         gDB = count1;
           %     case 2
           %         gDB = count2;
           %     case 3
           %         gDB = count3;
           % end            
        %end
        
        %recuperare le features dell'immagine passata come parametro 
        function searchFeatures(~, immagini, nameImage, folderFeatures)
            global queryImage
            
            for k = 1:length(immagini)
                nameImageFolder = immagini(k).name;
                if(strcmp(nameImage, nameImageFolder))
                    queryImage = folderFeatures(k,:);
                end
            end
            
        end
        
        
        % -------------------------- Funzione Zernike_Moments --------------------------
        function [zernike_mom] = Zernike_Moments(~,f, ord, repet)
            % Function to compute Zernike moments up to order ord.
            %       f = Input image M x N matrix (can be grayscale or color)
            %       ord = The maximum order (scalar)
            %       rep = repetition  number
            %       zernike_mom = Vector containing all the moments up to order ord
            %   
            % EXAMPLE
            %       
            %   RGBfile = imgetfile();
            %   RGB = imread(RGBfile);
            %   if size(RGB, 3)==1
            %       gray = RGB;
            %   else
            %       gray = rgb2gray(RGB);
            %   end
            %   ord = 4;
            %   repet = 2;
            %   mom = Zernike_Moments(gray, ord, repet);
            %   mom2 = Zernike_Moments(imrotate(gray, 90), ord, repet);
            %   mom3 = Zernike_Moments(imresize(gray, 0.5), ord, repet);
            %   MOM = [mom;mom2;mom3];
            if size(f,3)==3  
                f = rgb2gray(f); 
            end

            f = double(f);
            zernike_mom = [];

            for i=0:ord
                for j=0:repet
                    if i>=j && mod((i+j),2)==0
                        % Computation of Zernike moment with order i and repetition j 
                        [~, AOH, ~] = Zernikmoment_MN(f,i,j); 
                        zernike_mom = [zernike_mom AOH];
                    end
                end
            end  
        
            % -------------------------- Funzione Zernikemoment_MN --------------------------
            function [Z, A, Phi] = Zernikmoment_MN(f,n,m)
                % Function to find the Zernike moments for an M x N grayscale image
                %       f = Input image M x N matrix 
                %       n = The order of Zernike moment (scalar)
                %       m = The repetition number of Zernike moment (scalar)
                %       Z = Complex Zernike moment 
                %       A = Amplitude of the moment
                %       Phi = phase (angle) of the moment (in degrees)
                %
                [M, N]  = size(f);
                y = 1:M; x = 1:N;  % x row, y col

                [X,Y] = meshgrid(x,y);
                R = sqrt( (2.*X-N-1).^2/N^2 + (2.*Y-M-1).^2/M^2 );
                Theta = atan2((M-1-2.*Y+2)/M,(2.*X-N+1-2)/N);

                R = (R<=1).*R;
                Rad = radialpoly(R,n,m);    % get the radial polynomial
                Product = f.*Rad.*exp(-1i*m*Theta);
                Z = sum(Product(:));        % calculate the moments
 
                cnt = nnz(R)+1;             % count the number of pixels inside the unit circle
                Z = (n+1)*Z/cnt;            % normalize the amplitude of moments
                A = abs(Z);                 % calculate the amplitude of the moment
                Phi = angle(Z)*180/pi;      % calculate the phase of the moment (in degrees)
            end
            
            % -------------------------- Funzione radialpoly --------------------------
            function rad = radialpoly(r,n,m)
                % Function to compute Zernike Polynomials:
                %       r = radius
                %       n = the order of Zernike polynomial
                %       m = the repetition of Zernike moment
                rad = zeros(size(r));                     % Initilization
                for s = 0:(n-abs(m))/2
                    c = (-1)^s*factorial(n-s)/(factorial(s)*factorial((n+abs(m))/2-s)*factorial((n-abs(m))/2-s));
                rad = rad + c*r.^(n-2*s);
                
                end
            end
        end        
    
        function v = legmoms_vec(~, F,ord)
            %LEGMOMS_VEC vector of Legendre moments of an image
            %   v=legmoms_vec(F,ord) computes the vector v of the continuous Legendre
            %   moments of the image F, up to order ord.

            M = legmoms(F,ord);
            idx = fliplr(triu(ones(size(M))));
            v = M(logical(idx))';
            
            
            function [M, Md, featM, featMd] = legmoms(F, ord, usesimpson)
                %LEGMOMS Legendre moments of an image
                %   [M,Md]=legmoms(F,ord) computes the continuous Legendre moments M and the
                %   discrete moments Md of the image F, up to order ord.
                
                n = ord+1;
                type = 'Legendre';
                if nargin ==2
                 usesimpson = 1;	% use Simpson to compute integrals (otherwise, trapez. rule)
                end
                
                %%%%%%%%Aggiunto da Lorenzo
                [m1,m2] = size(F);
                if usesimpson && ~(2*round(m1/2)-m1), F = F(1:end-1,:); end % for Simpson
                if usesimpson && ~(2*round(m2/2)-m2), F = F(:,1:end-1); end % for Simpson
                %%%%%%%%
                
                [m1,m2] = size(F);
                F = im2double(F);
                x = linspace(-1,1,m1)';
                y = linspace(-1,1,m2)';
                
                [alfa1,beta1] = opcoef(type,n,m1);	% recursion coefficients
                [alfa2,beta2] = opcoef(type,n,m2);	% recursion coefficients
                P1 = opevmat(alfa1,beta1,x);		% values of polynomials on x
                P2 = opevmat(alfa2,beta2,y);		% values of polynomials on y
                
                % continuous moments
                M = opcmoms(F,P1,P2,usesimpson);
                Md = 4/m1/m2*(P1'*F*P2);	% discrete Legendre polynomials
                
                idx = zeros(size(Md));
                idx(1:end-1,1:end-1)= fliplr(triu(ones(size(Md)-1)));
                
                featM = M(logical(idx))';
                featMd = Md(logical(idx))';
            end
            
            function M = opcmoms(F,P1,P2,usesimpson,fast)
                %OPCMOMS numerical computation of the moments of an image
                %   M=opcmoms(F,P1,P2) computes the moments of the image F with respect to the
                %   basis functions whose values are contained in the columns of P1 and P2.
                %
                %   M=opcmoms(F,P1,P2,method) chooses between Simpson rule (method=1, default)
                %   and trapezoidal rule (method=0).
                
                if nargin<4 || isempty(usesimpson), usesimpson = 1; end
                if nargin<5 || isempty(fast), fast = 1; end
                
                [m1,m2] = size(F);
                n1 = size(P1,2)-1;
                n2 = size(P2,2)-1;
                
                if usesimpson && ~(2*round(m1/2)-m1), error('m1 must be odd.'), end
                if usesimpson && ~(2*round(m2/2)-m2), error('m2 must be odd.'), end
                
                if fast
                	M = zeros(n1+1,n2+1);
                	if usesimpson
                		w1 = ones(m1,1); w1(2:2:m1-1)=4; w1(3:2:m1-1)=2;
                		h1 = 2/3/(m1-1);
                		w2 = ones(m2,1); w2(2:2:m2-1)=4; w2(3:2:m2-1)=2;
                		h2 = 2/3/(m2-1);
                	else
                		w1 = [1 2*ones(1,m1-2) 1]';
                		h1 = 1/(m1-1);
                		w2 = [1 2*ones(1,m2-2) 1]';
                		h2 = 1/(m2-1);
                	end
                	for k = 1:n1+1
                		g = h1*(w1'*(F.*repmat(P1(:,k),1,m2)))';
                		M(k,:) = h2*(w2'*(repmat(g,1,n2+1).*P2));
                	end
                else	% slow
                	M = zeros(n1+1,n2+1);
                	g = zeros(m1,1);
                	for k = 1:n1+1
                		for l = 1:n2+1
                			if usesimpson
                				for i = 1:m2
                					g(i) = simpson(F(:,i).*P1(:,k),-1,1);
                				end
                				M(k,l) = simpson(g.*P2(:,l),-1,1);
                			else
                				for i = 1:m2
                					g(i) = trapezi(F(:,i).*P1(:,k),-1,1);
                				end
                				M(k,l) = trapezi(g.*P2(:,l),-1,1);
                			end
                		end
                	end
                end             
           end
            
            function P = opevmat(alfa,beta,x,ortho)
                %OPEVMAT evaluation of orthogonal polynomials
                %   P=opevmat(alpha,beta,x) computes the values of the orthogonal polynomials
                %   defined by the recursion coefficients alpha and beta on the vector of
                %   points x. The j-th column of P contains the values of the polynomial of
                %   degree j-1.
                %
                %   P=opevmat(alpha,beta,x,ortho) specifies the normalizazion of the
                %   polynomials: ortho=1 (default) evaluates orthonormal polynomials, ortho=0
                %   is for monic polynomials.
                
                if nargin<4, ortho = 1; end
                
                n = length(alfa)-1;
                m = length(x);
                x = x(:);
                P = ones(m,n+1);
                
                if ortho	% orthonormal
                	P(:,1) = P(:,1)/sqrt(beta(1));
                	P(:,2) = (x-alfa(1)).*P(:,1);
                	P(:,2) = P(:,2) ./ sqrt(beta(2));
                	for k = 2:n
                		P(:,k+1) = (x-alfa(k)).*P(:,k) - sqrt(beta(k)).*P(:,k-1);
                		P(:,k+1) = P(:,k+1) / sqrt(beta(k+1));
                	end
                else		% monic
                	P(:,2) = x-alfa(1);
                	for k = 2:n
                		P(:,k+1) = (x-alfa(k)).*P(:,k) - beta(k).*P(:,k-1);
                	end
                end             

                
            end
            
            function [alfa,beta] = opcoef(type,n,N)
                %OPCOEF	recursion coefficients for orthogonal polynomials
                %   [alpha,beta]=opcoef(type,n) returns the three-term recursion coefficients
                %   for the first n+1 orthogonal polynomials of some families, as specified by
                %   type: 'Legendre', 'DChebychev' (discrete Chebychev), 'Chebychev'.
                %
                %   If type='DChebychev', a third parameter N, specifying the number of points,
                %   is required. This family is orthogonal in [0,N-1], the other ones in
                %   [-1,1].
                
                if ischar(type)
                	switch lower(type(1:5))
                	case 'legen'	% Legendre
                		type = 1;
                	case 'dcheb'	% discrete Chebychev
                		type = 2;
                	case 'cheby'	% Chebychev
                		type = 3;
                	otherwise
                		error('unknown type.')
                	end
                end
                
                if type == 2
                	if nargin<3, error('the number of points is needed.'), end
                	if n+1>N, error('n must be lower than N-1.'), end
                end
                
                vk = [1:n+1]';
                
                switch type
                
                case 1		% Legendre
                	beta0 = 2;
                	alfa = zeros(n+1,1);
                	beta = [beta0; vk.^2./(4*vk.^2-1)];
                
                case 2		% discrete Chebychev
                	beta0 = N;
                	alfa = N/2*(1-1/N)*ones(n+1,1);
                	beta = [beta0; N^2/4*(1-(vk./N).^2)./(4-1./(vk.^2))];
                
                case 3		% Chebychev
                	beta0 = pi;
                	alfa = zeros(n+1,1);
                	beta = [beta0; 1/2; ones(n,1)/4];
                
                otherwise
                	error('unknown type.')
                end
              
            end
        end
    
        function [v,i,j] = dchebmoms_vec(~,F,ord)
            %DCHEBMOMSS_VEC vector of discrete Chebyshev moments of an image
            %   v=dchebmoms_vec(F,ord) computes the vector v of the discrete Chebyshev
            %   moments of the image F, up to order ord.
            
            M = dchebmoms(F,ord);
            idx = fliplr(triu(ones(size(M))));
            v = M(logical(idx))';
            
            
            if nargout > 1 
                idx2 = find(idx);
                [i,j] = ind2sub(size(M),idx2);
                %v = M(sub2ind(size(M),j,i));
                i = i-1;
                j = j-1;
            end
            
            function [M,Mc,P1,P2] = dchebmoms(F,ord)
                %DCHEBMOMS discrete Chebyshev moments of an image
                %   M=dchebmoms(F,ord) computes the matrix M of the discrete Chebyshev moments
                %   of the image F, up to order ord.
                %   [M,Mc]=dchebmoms(F,ord) computes also the continuous Chebyshev moments Mc.
                %   [M,Md,P1,P2]=dchebmoms(F,ord) returns the evaluation of orthogonal
                %   polynomials on both axes.
                
                n = ord;
                type = 'DChebyshev';
                usesimpson = 1 && (nargin>1);	% use Simpson to compute integrals
                
                [m1 m2] = size(F);
                if usesimpson && ~(2*round(m1/2)-m1), m1 = m1-1; end	% for Simpson
                if usesimpson && ~(2*round(m2/2)-m2), m2 = m2-1; end	% for Simpson
                F = double(F(1:m1,1:m2));
                F = mat2gray(F);
                x = [0:m1-1]';
                y = [0:m2-1]';
                
                [alfa1 beta1] = opcoef(type,n,m1);	% recursion coefficients
                [alfa2 beta2] = opcoef(type,n,m2);	% recursion coefficients
                P1 = opevmat(alfa1,beta1,x);		% values of polynomials on x
                P2 = opevmat(alfa2,beta2,y);		% values of polynomials on y
                
                % moments
                M = P1'*F*P2;
                if nargout>1, Mc = opcmoms(F,P1,P2,usesimpson); end
                    
            end
            
            function M = opcmoms(F,P1,P2,usesimpson,fast)
                %OPCMOMS numerical computation of the moments of an image
                %   M=opcmoms(F,P1,P2) computes the moments of the image F with respect to the
                %   basis functions whose values are contained in the columns of P1 and P2.
                %
                %   M=opcmoms(F,P1,P2,method) chooses between Simpson rule (method=1, default)
                %   and trapezoidal rule (method=0).
                
                if nargin<4 || isempty(usesimpson), usesimpson = 1; end
                if nargin<5 || isempty(fast), fast = 1; end
                
                [m1,m2] = size(F);
                n1 = size(P1,2)-1;
                n2 = size(P2,2)-1;
                
                if usesimpson && ~(2*round(m1/2)-m1), error('m1 must be odd.'), end
                if usesimpson && ~(2*round(m2/2)-m2), error('m2 must be odd.'), end
                
                if fast
                	M = zeros(n1+1,n2+1);
                	if usesimpson
                		w1 = ones(m1,1); w1(2:2:m1-1)=4; w1(3:2:m1-1)=2;
                		h1 = 2/3/(m1-1);
                		w2 = ones(m2,1); w2(2:2:m2-1)=4; w2(3:2:m2-1)=2;
                		h2 = 2/3/(m2-1);
                	else
                		w1 = [1 2*ones(1,m1-2) 1]';
                		h1 = 1/(m1-1);
                		w2 = [1 2*ones(1,m2-2) 1]';
                		h2 = 1/(m2-1);
                	end
                	for k = 1:n1+1
                		g = h1*(w1'*(F.*repmat(P1(:,k),1,m2)))';
                		M(k,:) = h2*(w2'*(repmat(g,1,n2+1).*P2));
                	end
                else	% slow
                	M = zeros(n1+1,n2+1);
                	g = zeros(m1,1);
                	for k = 1:n1+1
                		for l = 1:n2+1
                			if usesimpson
                				for i = 1:m2
                					g(i) = simpson(F(:,i).*P1(:,k),-1,1);
                				end
                				M(k,l) = simpson(g.*P2(:,l),-1,1);
                			else
                				for i = 1:m2
                					g(i) = trapezi(F(:,i).*P1(:,k),-1,1);
                				end
                				M(k,l) = trapezi(g.*P2(:,l),-1,1);
                			end
                		end
                	end
                end
            end
            
            function P = opevmat(alfa,beta,x,ortho)
                %OPEVMAT evaluation of orthogonal polynomials
                %   P=opevmat(alpha,beta,x) computes the values of the orthogonal polynomials
                %   defined by the recursion coefficients alpha and beta on the vector of
                %   points x. The j-th column of P contains the values of the polynomial of
                %   degree j-1.
                %
                %   P=opevmat(alpha,beta,x,ortho) specifies the normalizazion of the
                %   polynomials: ortho=1 (default) evaluates orthonormal polynomials, ortho=0
                %   is for monic polynomials.
                
                if nargin<4, ortho = 1; end
                
                n = length(alfa)-1;
                m = length(x);
                x = x(:);
                P = ones(m,n+1);
                
                if ortho	% orthonormal
                	P(:,1) = P(:,1)/sqrt(beta(1));
                	P(:,2) = (x-alfa(1)).*P(:,1);
                	P(:,2) = P(:,2) ./ sqrt(beta(2));
                	for k = 2:n
                		P(:,k+1) = (x-alfa(k)).*P(:,k) - sqrt(beta(k)).*P(:,k-1);
                		P(:,k+1) = P(:,k+1) / sqrt(beta(k+1));
                	end
                else		% monic
                	P(:,2) = x-alfa(1);
                	for k = 2:n
                		P(:,k+1) = (x-alfa(k)).*P(:,k) - beta(k).*P(:,k-1);
                	end
                end  
            end
            
            function [alfa,beta] = opcoef(type,n,N)
                %OPCOEF	recursion coefficients for orthogonal polynomials
                %   [alpha,beta]=opcoef(type,n) returns the three-term recursion coefficients
                %   for the first n+1 orthogonal polynomials of some families, as specified by
                %   type: 'Legendre', 'DChebychev' (discrete Chebychev), 'Chebychev'.
                %
                %   If type='DChebychev', a third parameter N, specifying the number of points,
                %   is required. This family is orthogonal in [0,N-1], the other ones in
                %   [-1,1].
                
                if ischar(type)
                	switch lower(type(1:5))
                	case 'legen'	% Legendre
                		type = 1;
                	case 'dcheb'	% discrete Chebychev
                		type = 2;
                	case 'cheby'	% Chebychev
                		type = 3;
                	otherwise
                		error('unknown type.')
                	end
                end
                
                if type == 2
                	if nargin<3, error('the number of points is needed.'), end
                	if n+1>N, error('n must be lower than N-1.'), end
                end
                
                vk = [1:n+1]';
                
                switch type
                
                case 1		% Legendre
                	beta0 = 2;
                	alfa = zeros(n+1,1);
                	beta = [beta0; vk.^2./(4*vk.^2-1)];
                
                case 2		% discrete Chebychev
                	beta0 = N;
                	alfa = N/2*(1-1/N)*ones(n+1,1);
                	beta = [beta0; N^2/4*(1-(vk./N).^2)./(4-1./(vk.^2))];
                
                case 3		% Chebychev
                	beta0 = pi;
                	alfa = zeros(n+1,1);
                	beta = [beta0; 1/2; ones(n,1)/4];
                
                otherwise
                	error('unknown type.')
                end             
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            % variabili globali 
            global path 
            global immagini
            global count1
            global count2
            global count3
            %global count4
            global imageVector
            
            vector = {};
            
            count1 = 0;
            count2 = 0;
            count3 = 0;
            %count4 = 0; %counter per OASIS
                                          
            path = uigetdir;                       % 'uigetdir' recupera il path in base alla cartella selezionata dall'utente
                  
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
                        
            % inserito lo stesso controllo presente nell'inserimento
            % dell'immagine, in modo che non venga generato un errore nel
            % caso in cui l'utente dovesse premere per sbaglio sul tasto
            % per annullare la selezione di una cartella
            
            if(ischar(path))
                app.DirectoryPathLabel.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 

                a = dir(path);                                      % recupero il contenuto della cartella attraverso il path e la funzione 'dir'
                imageVector = {a(:).name}';                         % recupero tutti i nomi dei vari file presenti nella cartella e li salvo in un array
                imageVector(ismember(imageVector,{'.','..'})) = []; % rimuovo eventuali 'punti' o 'doppi punti' che non sono necessari
                app.DirectoryContents.Data = imageVector;           % mostro il contenuto della cartella nella tabella "Directory Contents"

            end
            
            %display(imageVector{4});
            %fprintf('\nStampa Nome di Prova\n');
            %display(immagini(4).name);
                        
            % Ciclo che si occupa di contare quante immagini sono presenti
            % all'interno della cartella suddividendole in classi.
            % Conteggio necessario alla fine per il calcolo delle
            % performance            
            for k = 1:length(immagini)
                classeCartella = extractBetween(imageVector{k}, "_", ".");
                classeCartella = str2num(classeCartella{1:1});

                switch classeCartella
                    case 1
                        count1 = count1 + 1;
                    case 2
                        count2 = count2 + 1;
                    case 3
                        count3 = count3 + 1;
                    %case 2
                        %count4 = count4 + 1; %counter per OASIS
                end             
            end
           fprintf("\nLe immagini della prima classe sono: %d", count1);
           fprintf("\nLe immagini della seconda classe sono: %d", count2);
           fprintf("\nLe immagini della terza classe sono: %d", count3);
           %fprintf("\nLe immagini della quarta classe sono: %d\n", count4); %stampa per OASIS


        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global image1     
            global nameImage
            global ext
            global classeScelta
            
            filterImage = {'*.tiff;*.jpg;*.png;*.gif;*.tif;*.bmp', 'All Image Files'}; %imposto il filtro per la scelta delle immagini (in base al tipo di file)
            
            [file, path] =  uigetfile(filterImage); % l'utente sceglie un'immagine sulla base del filtro presente
            
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
          
                        
            % con questo controllo faccio in modo che l'applicazione non
            % vada avanti fino a quando l'utente non inserisce un'immagine,
            % evitando il crash nel caso in cui decida di provare a uscire
            % dalla finestra di dialogo che viene generata
            if(ischar(path))
                nameImage = file;                   % mi prendo il nome dell'immagine scelta per effettuare tutti i confronti
                
                classeScelta = extractBetween (nameImage, "_", "."); % mi recupero la classe dell'immagine scelta dall'utente e la salvo
                classeScelta = str2num(classeScelta{1:1});
                
                %fprintf('\n--------------------------------------------------------')
                %fprintf('\n\nClasse immagine scelta: %d', classeScelta);

                fullpathname = strcat(path, file);
                image1 = imread(fullpathname);
                [~,~,ext] = fileparts(file);
            end
            display(classeScelta);
            
            app.photoPicked.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente
            app.photoPicked_3.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente
            app.photoPicked_4.Text = nameImage; % inserisco nell'etichetta il nome dell'immagine scelta dall'utente

            % ----------------------- Emphysema-CT -----------------------
            %img8 = im2uint8(mat2gray(image1)); %conversione dell'immagine in 8bit
            %img8 = imadjust(img8);
            img8 = image1;
            imshow(img8, 'Parent', app.UIAxes); % mostro a video l'immagine al posto del piano
            imshow(img8, 'Parent', app.UIAxes_2); % mostro a video l'immagine al posto del piano
            % ------------------------------------------------------------
            
            % ----------------------- OASIS ------------------------------
            %imshow(image1, 'Parent', app.UIAxes); % mostro a video l'immagine al posto del piano
            %imshow(image1, 'Parent', app.UIAxes_2); % mostro a video l'immagine al posto del piano
            %-------------------------------------------------------------

        end

        % Button pushed function: EuclideanDistanceButton
        function EuclideanDistanceButtonPushed(app, event)
            %visualizzazione a video delle immagini utilizzando la formula
            %della distanza euclidea
            global immagini
            global imageFeatures
            global folderFeatures
            global distance
       
            
            for k = 1:length(immagini)
               baseFileName = immagini(k).name;
               
               %salvo dentro un vettore tutti i nomi delle immagini
               nomeRiga(k, :) = {baseFileName}; 
               
               %salvo dentro un vettore tutti i valori della distanza euclidea
               distance(k, :) = pdist2(folderFeatures(k,:), imageFeatures(1,:), 'euclidean');
            end
            %distance = normalize(distance, 'range', [0,1]);

            % ---------------- Tabella in cui vengono salvati i valori di dissimilarità riordinati ----------------
            app.T14 = table(distance, 'RowNames', nomeRiga);           
            app.T14 = sortrows(app.T14);                % riordinamento delle righe della tabella con le distanze
            app.Euclidean_Results.Data = app.T14{:,:};  % scrittura dati all'interno della tabella interfaccia (valori dissimilarità)
            app.Euclidean_Results.RowName = app.T14.Properties.RowNames; % scrittura nomi delle righe per avere il corrispettivo dell'immagine           
                   
            
            % ---------------- Tabella immagine 1 ----------------
            app.T15 = table(app.T14{2,:}); %creazione tabella
            app.dates1_2.Data = app.T15{:,:}; %assegnazione valori
            app.dates1_2.RowName = app.T14.Properties.RowNames(2); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(2), app.photo1_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 2 ----------------
            app.T16 = table(app.T14{3,:}); %creazione tabella
            app.dates2_2.Data = app.T16{:,:}; %assegnazione valori
            app.dates2_2.RowName = app.T14.Properties.RowNames(3); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(3), app.photo2_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 3 ----------------
            app.T17 = table(app.T14{4,:}); %creazione tabella
            app.dates3_2.Data = app.T17{:,:}; %assegnazione valori
            app.dates3_2.RowName = app.T14.Properties.RowNames(4); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(4), app.photo3_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 4 ----------------
            app.T18 = table(app.T14{5,:}); %creazione tabella
            app.dates4_2.Data = app.T18{:,:}; %assegnazione valori
            app.dates4_2.RowName = app.T14.Properties.RowNames(5); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(5), app.photo4_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 5 ----------------
            app.T19 = table(app.T14{6,:}); %creazione tabella
            app.dates5_2.Data = app.T19{:,:}; %assegnazione valori
            app.dates5_2.RowName = app.T14.Properties.RowNames(6); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(6), app.photo5_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 6 ----------------
            app.T20 = table(app.T14{7,:}); %creazione tabella
            app.dates6_2.Data = app.T20{:,:}; %assegnazione valori
            app.dates6_2.RowName = app.T14.Properties.RowNames(7); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(7), app.photo6_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 7 ----------------
            app.T21 = table(app.T14{8,:}); %creazione tabella
            app.dates7_2.Data = app.T21{:,:}; %assegnazione valori
            app.dates7_2.RowName = app.T14.Properties.RowNames(8); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(8), app.photo7_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 8 ----------------
            app.T22 = table(app.T14{9,:}); %creazione tabella
            app.dates8_2.Data = app.T22{:,:}; %assegnazione valori
            app.dates8_2.RowName = app.T14.Properties.RowNames(9); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(9), app.photo8_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 9 ----------------
            app.T23 = table(app.T14{10,:}); %creazione tabella
            app.dates9_2.Data = app.T23{:,:}; %assegnazione valori
            app.dates9_2.RowName = app.T14.Properties.RowNames(10); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(10), app.photo9_2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 10 ----------------
            app.T24 = table(app.T14{11,:}); %creazione tabella
            app.dates10_2.Data = app.T24{:,:}; %assegnazione valori
            app.dates10_2.RowName = app.T14.Properties.RowNames(11); %riempimento tabella interfaccia
            search(app, immagini, app.T14.Properties.RowNames(11), app.photo10_2); %ricerca immagine e visualizzazione interfaccia
        end

        % Button pushed function: SimilarityButton
        function SimilarityButtonPushed(app, event)
            global immagini
            global imageFeatures
            global folderFeatures  %vettore contenente le immagini del dataset con i calcoli del momento scelto
            
            similarity = 0;
            % -----------------------------------------------------------
            % formula per il calcolo della SIMILARITÀ in riferimento alla
            % singola immagine scelta all'inizio dall'utente
            % -----------------------------------------------------------
            
            for i = 1:length(immagini)
               baseFileName = immagini(i).name;
               nomeRiga(i, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                    
               similarity = abs((folderFeatures(i,:) - imageFeatures(1,:))/(1 + folderFeatures(i,:) + imageFeatures(1,:)));                
               line(i, :) = similarity;               
            end            
            %line = normalize(line, 'range', [0,1]); % normalizzazione dei risultati con valori compresi tra zero e uno    


            % ---------------- Tabella in cui vengono salvati i valori di dissimilarità riordinati ----------------
            app.T2 = table(line, 'RowNames', nomeRiga);            
            app.T2 = sortrows(app.T2); %riordinamento tabella partendo dall'immagine con valore di similarità pari a 0            
            app.Similarity_Results.Data = app.T2{:,:};  % scrittura dati all'interno della tabella interfaccia (valori dissimilarità)
            app.Similarity_Results.RowName = app.T2.Properties.RowNames; % scrittura nomi delle righe per avere il corrispettivo dell'immagine
            
            % display(app.T2); per stampare tutta la tabella
            
            % display(app.T2.Properties.RowNames{3,:}); stampo la riga
            % della tabella senza usare l'interfaccia
                     
            
            % ---------------- Tabella immagine 1 ----------------
            app.T3 = table(app.T2{2,:}); %creazione tabella
            app.dates1.Data = app.T3{:,:}; %assegnazione valori
            app.dates1.RowName = app.T2.Properties.RowNames(2); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(2), app.photo1); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 2 ----------------
            app.T4 = table(app.T2{3,:}); %creazione tabella
            app.dates2.Data = app.T4{:,:}; %assegnazione valori
            app.dates2.RowName = app.T2.Properties.RowNames(3); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(3), app.photo2); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 3 ----------------
            app.T5 = table(app.T2{4,:}); %creazione tabella
            app.dates3.Data = app.T5{:,:}; %assegnazione valori
            app.dates3.RowName = app.T2.Properties.RowNames(4); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(4), app.photo3); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 4 ----------------
            app.T6 = table(app.T2{5,:}); %creazione tabella
            app.dates4.Data = app.T6{:,:}; %assegnazione valori
            app.dates4.RowName = app.T2.Properties.RowNames(5); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(5), app.photo4); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 5 ----------------
            app.T7 = table(app.T2{6,:}); %creazione tabella
            app.dates5.Data = app.T7{:,:}; %assegnazione valori
            app.dates5.RowName = app.T2.Properties.RowNames(6); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(6), app.photo5); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 6 ----------------
            app.T8 = table(app.T2{7,:}); %creazione tabella
            app.dates6.Data = app.T8{:,:}; %assegnazione valori
            app.dates6.RowName = app.T2.Properties.RowNames(7); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(7), app.photo6); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 7 ----------------
            app.T9 = table(app.T2{8,:}); %creazione tabella
            app.dates7.Data = app.T9{:,:}; %assegnazione valori
            app.dates7.RowName = app.T2.Properties.RowNames(8); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(8), app.photo7); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 8 ----------------
            app.T10 = table(app.T2{9,:}); %creazione tabella
            app.dates8.Data = app.T10{:,:}; %assegnazione valori
            app.dates8.RowName = app.T2.Properties.RowNames(9); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(9), app.photo8); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 9 ----------------
            app.T11 = table(app.T2{10,:}); %creazione tabella
            app.dates9.Data = app.T11{:,:}; %assegnazione valori
            app.dates9.RowName = app.T2.Properties.RowNames(10); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(10), app.photo9); %ricerca immagine e visualizzazione interfaccia
            
            % ---------------- Tabella immagine 10 ----------------
            app.T12 = table(app.T2{11,:}); %creazione tabella
            app.dates10.Data = app.T12{:,:}; %assegnazione valori
            app.dates10.RowName = app.T2.Properties.RowNames(11); %riempimento tabella interfaccia
            search(app, immagini, app.T2.Properties.RowNames(11), app.photo10); %ricerca immagine e visualizzazione interfaccia
        
        end

        % Selection changed function: 
        % WhatmomentdoyouwanttocalculateButtonGroup
        function WhatmomentdoyouwanttocalculateButtonGroupSelectionChanged(app, event)
            global selectedButton
            selectedButton = app.WhatmomentdoyouwanttocalculateButtonGroup.SelectedObject.Text;
               
            switch selectedButton
                case 'HU'
                    app.GetOrderEditField.Visible = 'off';
                    app.GetOrderEditFieldLabel.Visible = 'off';
                    app.GetRepEditFieldLabel.Visible = 'off';
                    app.GetRepEditField.Visible = 'off';
            
                    app.CalculateButton.Visible = 'on';
                    %disp(selectedButton);

                case 'Zernike'
                    app.GetOrderEditField.Visible = 'on';
                    app.GetOrderEditFieldLabel.Visible = 'on';
                    app.GetRepEditFieldLabel.Visible = 'on';
                    app.GetRepEditField.Visible = 'on';
            
                    app.CalculateButton.Visible = 'on';
                    %disp(selectedButton);

                    
                case 'Legendre'
                    app.GetOrderEditField.Visible = 'on';
                    app.GetOrderEditFieldLabel.Visible = 'on';
                    app.GetRepEditFieldLabel.Visible = 'off';
                    app.GetRepEditField.Visible = 'off';
                    app.GetRepEditField.Value = 0;
            
                    app.CalculateButton.Visible = 'on';
                    %disp(selectedButton);

                case 'Chebyshev'
                    app.GetOrderEditField.Visible = 'on';
                    app.GetOrderEditFieldLabel.Visible = 'on';
                    app.GetRepEditFieldLabel.Visible = 'off';
                    app.GetRepEditField.Visible = 'off';
                    app.GetRepEditField.Value = 0;

                    app.CalculateButton.Visible = 'on';
                    %disp(selectedButton);
           
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % variabili globali         
            global path            %path cartella
            global nameImage       %nomeImmagine
            global immagini        %contenuto cartella
            global imageFeatures   %vettore features dell'immagine scelta
            global folderFeatures  %vettore features della cartella   
            
            global selectedButton
            global ext
           
            ord = app.GetOrderEditField.Value;
            rep = app.GetRepEditField.Value;   
            
            folderFeatures = [];
            imageFeatures = [];           
        
            
            % vado a creare il file con l'estensione che dovrebbe essere
            % contenuto nella cartella per automatizzare il processo. Si
            % ipotizza che all'interno della cartella scelta dall'utente
            % tutte le immagini abbiano la medesima estensione di quella
            % scelta singolarmente
            content = '*';
            extension = ext;            
            word = strcat(content,extension);
            
            
            pattern = fullfile(path, word); % -------- dovrebbe essere automatizzato con l'utilizzo di word --------
            immagini = dir(pattern);      
            
                    
            if rep <= ord

                switch selectedButton
                    case 'HU'                  
                        % ciclo per scorrere il vettore in cui sono contenute le
                        % immagini, applicare la funzione e salvare ogni valore in una
                        % riga della seconda tabella
                      
                        for k = 1:length(immagini)
                            baseFileName = immagini(k).name;
                            fullFileName = fullfile(path, baseFileName);
                            image = imread(fullFileName);
                            
                            % ----------- da commentare quando si utilizza OASIS -----------                        
                            %image = im2uint8(mat2gray(image));
                            %image = imadjust(image);

                            
                            nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                            folderFeatures(k, :) = app.Hu_Moments(image); %salvo in un vettore tutti i valori delle feautures                    
                        end               
    
                    case 'Zernike'
                        for k = 1:length(immagini)
                            baseFileName = immagini(k).name;
                            fullFileName = fullfile(path, baseFileName);
                            image = imread(fullFileName);
                            
                            % ----------- da commentare quando si utilizza OASIS -----------                        
                            %image = im2uint8(mat2gray(image));
                            %image = imadjust(image);

                                        
                            nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                            folderFeatures(k, :) = app.Zernike_Moments(image, ord, rep); %salvo in un vettore tutti i valori delle feautures                    
                        end
                        
                    case 'Legendre'
                        for k = 1:length(immagini)
                            baseFileName = immagini(k).name;
                            fullFileName = fullfile(path, baseFileName);
                            image = imread(fullFileName);
                            
                            % ----------- da commentare quando si utilizza OASIS -----------                        
                            %image = im2uint8(mat2gray(image));
                            %image = imadjust(image);
                                
                            nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                            folderFeatures(k, :) = app.legmoms_vec(image, ord); %salvo in un vettore tutti i valori delle feautures                    
                        end
                        
                    case 'Chebyshev'
                        for k = 1:length(immagini)
                            baseFileName = immagini(k).name;
                            fullFileName = fullfile(path, baseFileName);
                            image = imread(fullFileName);
                            
                            % ----------- da commentare quando si utilizza OASIS -----------                        
                            %image = im2uint8(mat2gray(image));
                            %image = imadjust(image);

                                
                            nomeRiga(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini                
                            folderFeatures(k, :) = app.dchebmoms_vec(image, ord); %salvo in un vettore tutti i valori delle feautures                    
                        end
                    
                end              
                
                %folderFeatures = normalize(folderFeatures, 'range', [0,1]);
                
                %recupero le features dell'immagine desiderata
                for k = 1:length(immagini)
                    nameImageFolder = immagini(k).name;
                    if (strcmp(nameImage, nameImageFolder))
                        imageFeatures = folderFeatures(k,:);     
                    end                
                end
                
                assignin('base','folderFeatures',folderFeatures)
                
                % ---------------------------------------------------------
                %funzione per ricercare le features sulla base di
                %nameImage(immagine usata come riferimento)
                %searchFeatures(app, immagini, nameImage, folderFeatures);
                % ---------------------------------------------------------
                %disp(nameImage); stampa per le prove
                
                % ---------------- Tabella in cui vengono salvate le features della cartella ----------------
                app.T13 = table(folderFeatures, 'RowNames', nomeRiga);  
                    
                app.Features_Dataset.Data = app.T13{:,:}; % salvo i valori ottenuti all'interno della tabella
                    
                app.Features_Dataset.RowName = app.T13.Properties.RowNames; 
                
           else
                f = errordlg('Error! The value of ORD must be "greater than"/"equal to" the REP value');
                uiwait(f);            
           end
        end

        % Button pushed function: SimilarityPerformanceButton
        function SimilarityPerformanceButtonPushed(app, event)
            format bank 
            global immagini
            global folderFeatures  %vettore contenente le immagini del dataset con i calcoli del momento scelto
            
            global imageVector
            global count1
            global count2
            global count3
            global count4
            
            global queryImage
            
            similarity = 0;
            
            rDB = 0;
            gDB = 0;
            ARP = 0;
            ARR = 0;
            FScore = 0;
            mAP = 0;
            eta = 100;


            fprintf('\n\n------------------------ Similarità -----------------------\n')        
            
            % ------------- Formula per il calcolo di ARP (Similarità)-------------        
            for i = 1:length(immagini)
                rDB = 0;
                
                %recupero la classe dell'i-esima immagine del dataset
                classeImmagine = extractBetween(imageVector{i}, "_", "."); 
                classeImmagine = str2num(classeImmagine{1:1});
                
                %recupero le features dell'i-esima immagine
                searchFeatures(app, immagini, imageVector{i}, folderFeatures);
                %for m = 1:length(immagini)
                %    nameImageFolder = immagini(m).name;
                %    if(strcmp(imageVector{i}, nameImageFolder))
                %        imageFeatures = folderFeatures(m,:);
                %    end
                %end
                
               % ---------------------------------------------------------
               % Formula per calcolare la similarità sulla base dell'iesima
               % immagine dell'iterazione
               % ---------------------------------------------------------
               for j = 1:length(immagini)
                    baseFileName = immagini(j).name;
                    nomeRigaS(j, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini     
               
                    similarityS = abs((folderFeatures(j,:) - queryImage(1,:))/(1 + folderFeatures(j,:) + queryImage(1,:)));
                
                    tableSimilarity(j, :) = similarityS;
               
                end
                
                % tabelle provvisorie per il calcolo di rDBi successivo
                %tableSimilarity = normalize(tableSimilarity, 'range', [0,1]);                
                app.TRY = table(tableSimilarity, 'RowNames', nomeRigaS);
                app.TRY = sortrows(app.TRY);
                
                
                %calcolo rDBi in base alla classe dell'immagine sopra
                for k = 1 : eta
                    classeRisultato = extractBetween(app.TRY.Properties.RowNames{k,:}, "_", ".");
                    classeRisultato = str2num(classeRisultato{1:1});
                    if(classeRisultato == classeImmagine)
                        rDB = rDB + 1;
                    end
                end                   
                %salvo il valore della sommatoria
                ARP = ARP + rDB/eta;
            end         
            %calcolo il valore finale
            ARP = (100/length(immagini)) * ARP;  
            fprintf('\n\nIl valore di ARP è: %.2f %', ARP);
            % ---------------------------------------------------------   
            
            
            % ------------- Formula per il calcolo di ARR (Similarità)-------------        
            for i = 1:length(immagini)
                rDB = 0;
                %recupero la classe dell'i-esima immagine del dataset
                classeImmagine = extractBetween(imageVector{i}, "_", "."); 
                classeImmagine = str2num(classeImmagine{1:1});
                
                searchFeatures(app, immagini, imageVector{i}, folderFeatures);
                %for m = 1:length(immagini)
                %    nameImageFolder = immagini(m).name;
                %    if(strcmp(imageVector{i}, nameImageFolder))
                %        imageFeatures = folderFeatures(m,:);
                %    end
                %end
                
               for j = 1:length(immagini)
                    baseFileName = immagini(j).name;
                    nomeRigaS(j, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini     
               
                    similarityS = abs((folderFeatures(j,:) - queryImage(1,:))/(1 + folderFeatures(j,:) + queryImage(1,:)));
                
                    tableSimilarity(j, :) = similarityS;
               
                end
            
                %tableSimilarity = normalize(tableSimilarity, 'range', [0,1]); 
                
                app.TRY = table(tableSimilarity, 'RowNames', nomeRigaS);
                app.TRY = sortrows(app.TRY);
                
                for k = 1 : eta
                    classeRisultato = extractBetween(app.TRY.Properties.RowNames{k,:}, "_", ".");
                    classeRisultato = str2num(classeRisultato{1:1});
                    if(classeRisultato == classeImmagine)
                        rDB = rDB + 1;
                    end
                end  
                % per OASIS abbiamo 4 classi(0, 0.5, 1, 2)
                % per Emphysema abbiamo 3 classi (1, 2, 3)
                switch (classeImmagine)
                    case 1
                        gDB = count1;
                    case 2
                        gDB = count2;
                    case 3
                        gDB = count3;
                    %case 2
                    %    gDB = count4;
                end       
                                
                ARR = ARR + rDB/gDB;
            end             
            ARR = (100/length(immagini)) * ARR;            
            fprintf('\n\nIl valore di ARR è: %.2f %', ARR);
            % --------------------------------------------------------- 
         
            
            % ------------ Formula per il calcolo di FScore (Similarità)-----------         
            FScore = (2 * ARP * ARR)/(ARP + ARR);
            fprintf('\n\nIl valore di FScore è: %.2f %', FScore);
            % --------------------------------------------------------- 
            
            nomiMisure(1,:) = "ARP";
            nomiMisure(2,:) = "ARR";
            nomiMisure(3,:) = "FScore";
            
            performance(1,:) = ARP;
            performance(2,:) = ARR;
            performance(3,:) = FScore;
            
            app.RESULT2 = table(performance, 'RowNames', nomiMisure);
            app.Performance_Similarita.ColumnFormat = {'bank'};
            app.Performance_Similarita.Data = app.RESULT2{:,:};
            app.Performance_Similarita.RowName = app.RESULT2.Properties.RowNames;
            
            
            % ------------- Formula per il calcolo di mAP (Similarità)-------------
            %for i = 1:length(immagini)
               %rDB = 0;
               %aux = 0; 
               % recupero la classe dell'i-esima immagine del dataset
               %classeImmagine = extractBetween(imageVector{i}, "-", ".gif"); 
               %classeImmagine = str2num(classeImmagine{1:1});
               
               % salvo il valore di gDB per l'i-esima immagine
               % per OASIS aggiungo un counter
               %switch (classeImmagine)
               %     case 0
               %         gDB = count1;
               %     case 0.5
               %         gDB = count2;
               %     case 1
               %         gDB = count3;
               %     case 2
               %         gDB = count4;
               %end       
               
               %searchFeatures(app, immagini, imageVector{i}, folderFeatures);
               
                %for m = 1:length(immagini)
                %    nameImageFolder = immagini(m).name;
                %    if(strcmp(imageVector{i}, nameImageFolder))
                %        imageFeatures = folderFeatures(m,:);
                %    end
                %end
                
               %for k = 1:length(immagini)
               %     baseFileName = immagini(k).name;
               %     nomeRigaS(k, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini     
               
               %     similarityS = abs((folderFeatures(k,:) - queryImage(1,:))/(1 + folderFeatures(k,:) + queryImage(1,:)));
                
               %     tableSimilarity(k, :) = similarityS;
               
                %end
            
                %tableSimilarity = normalize(tableSimilarity, 'range', [0,1]); 
                
                %app.TRY = table(tableSimilarity, 'RowNames', nomeRigaS);
                %app.TRY = sortrows(app.TRY);
               
               %for j = 1:eta
               %     classeRisultato = extractBetween(app.TRY.Properties.RowNames{j,:}, "-", ".gif");
               %     classeRisultato = str2num(classeRisultato{1:1});
               %     if(classeRisultato == classeImmagine)
               %         rDB = rDB + 1;
               %     end
               %     aux = aux + rDB/j;
               %end               
               %mAP = mAP + aux;
               
               % inizializzo un vettore che conterrà tutti i risultati
               % delle somme e lo riempio 
               %xl = zeros(gDB+1, 1);
               %for n = 1:gDB
               %    xl(n) = (rDB/n);                   
               %end
               %mAP = sum(xl);
            %end
            %mAP = (100/length(immagini)) * mAP;  
            
            %fprintf('\n\nIl valore di mAP è: %.2f %', mAP);
            % ---------------------------------------------------------
            
            fprintf('\n-----------------------------------------------------------')
        end

        % Button pushed function: EuclideanPerformanceButton
        function EuclideanPerformanceButtonPushed(app, event)
            format bank 
            global immagini
            global folderFeatures  %vettore contenente le immagini del dataset con i calcoli del momento scelto
            
            global imageVector
            global count1
            global count2
            global count3
            global count4
            
            global queryImage
            
            similarity = 0;
            
            rDB = 0;
            gDB = 0;
            ARP = 0;
            ARR = 0;
            FScore = 0;
            mAP = 0;
            eta = 100;
            
            % ciclo per calcolare quante immagini (dopo il calcolo della distanza euclidea)
            % hanno la stessa classe di quella dell'immagine scelta
            % all'inizio dall'utente, sulla base di un intervallo fissato
            % come eta
            % Determinazione di rDB
            
            %calcolo rDBi in base alla classe dell'immagine sopra
     
            fprintf('\n\n---------------------Distanza Euclidea---------------------')
            %fprintf(['\n\nIl totale di immagini con stessa classe di quella scelta' ...
            %    '\nnei risultati della distanza euclidea: %d'], rDB);
                       
            
            % ------------- Formula per il calcolo di ARP -------------        
            for i = 1:length(immagini)
                rDB = 0;
                
                %recupero la classe dell'i-esima immagine del dataset
                classeImmagine = extractBetween(imageVector{i}, "_", "."); 
                classeImmagine = str2num(classeImmagine{1:1});
                
                
                % funzione per ricercare le features dell'i-esima immagine
                searchFeatures(app, immagini, imageVector{i}, folderFeatures);
                
               % ---------------------------------------------------------
               % Formula per calcolare la distanza euclidea sulla base 
               % dell'iesima immagine dell'iterazione
               % ---------------------------------------------------------
                for j = 1:length(immagini)
                    baseFileName = immagini(j).name;
               
                    nomeRigaE(j, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini
               
                    euDistance(j, :) = pdist2(folderFeatures(j,:), queryImage(1,:), 'euclidean');
                end
                %euDistance = normalize(euDistance, 'range', [0,1]);
                app.TRY1 = table(euDistance, 'RowNames', nomeRigaE);
                app.TRY1 = sortrows(app.TRY1);


                %calcolo rDBi in base alla classe dell'immagine sopra
                for k = 1 : eta
                    classeRisultato = extractBetween(app.TRY1.Properties.RowNames{k,:}, "_", ".");
                    classeRisultato = str2num(classeRisultato{1:1});
                    if(classeRisultato == classeImmagine)
                        rDB = rDB + 1;
                    end
                end                   
                %salvo il valore della sommatoria
                ARP = ARP + rDB/eta;
            end         
            %calcolo il valore finale
            ARP = (100/length(immagini)) * ARP;  
            fprintf('\n\nIl valore di ARP è: %.2f %', ARP);
            % ---------------------------------------------------------        

            
            % ------------- Formula per il calcolo di ARR -------------        
            for i = 1:length(immagini)
                rDB = 0;
                %recupero la classe dell'i-esima immagine del dataset
                classeImmagine = extractBetween(imageVector{i}, "_", "."); 
                classeImmagine = str2num(classeImmagine{1:1});
                
                searchFeatures(app, immagini, imageVector{i}, folderFeatures);
                
                
               % ---------------------------------------------------------
               % Formula per calcolare la distanza euclidea sulla base 
               % dell'iesima immagine dell'iterazione
               % ---------------------------------------------------------
                for j = 1:length(immagini)
                    baseFileName = immagini(j).name;
               
                    nomeRigaE(j, :) = {baseFileName}; %salvo dentro un vettore tutti i nomi delle immagini
               
                    euDistance(j, :) = pdist2(folderFeatures(j,:), queryImage(1,:), 'euclidean');
                end
                %euDistance = normalize(euDistance, 'range', [0,1]);
                app.TRY1 = table(euDistance, 'RowNames', nomeRigaE);
                app.TRY1 = sortrows(app.TRY1);

                
                for k = 1 : eta
                    classeRisultato = extractBetween(app.TRY1.Properties.RowNames{k,:}, "_", ".");
                    classeRisultato = str2num(classeRisultato{1:1});
                    if(classeRisultato == classeImmagine)
                        rDB = rDB + 1;
                    end
                end                
                switch (classeImmagine)
                    case 1
                        gDB = count1;
                    case 2
                        gDB = count2;
                    case 3
                        gDB = count3;
                    %case 2
                    %    gDB = count4;
                end       
                                
                ARR = ARR + rDB/gDB;
            end             
            ARR = (100/length(immagini)) * ARR;            
            fprintf('\n\nIl valore di ARR è: %.2f %', ARR);
            % ---------------------------------------------------------  
            
            
            % ------------ Formula per il calcolo di FScore -----------         
            FScore = (2 * ARP * ARR)/(ARP + ARR);
            fprintf('\n\nIl valore di FScore è: %.2f %', FScore);
            % ---------------------------------------------------------  
            
            nomiMisure(1,:) = "ARP";
            nomiMisure(2,:) = "ARR";
            nomiMisure(3,:) = "FScore";
            
            performance(1,:) = ARP;
            performance(2,:) = ARR;
            performance(3,:) = FScore;
            
            app.RESULT1 = table(performance, 'RowNames', nomiMisure);
            app.Performance_Euclide.ColumnFormat = {'bank'};
            app.Performance_Euclide.Data = app.RESULT1{:,:};
            app.Performance_Euclide.RowName = app.RESULT1.Properties.RowNames;
            
            % ------------- Formula per il calcolo di mAP -------------
            
            %for i = 1:length(immagini)
            %   rDB = 0;
            %   aux = 0; 
               % recupero la classe dell'i-esima immagine del dataset
            %   classeImmagine = extractBetween(imageVector{i}, "-", ".gif"); 
            %   classeImmagine = str2num(classeImmagine{1:1});
               
               % salvo il valore di gDB per l'i-esima immagine
            %   switch (classeImmagine)
            %        case 0
            %            gDB = count1;
            %        case 0.5
            %            gDB = count2;
            %        case 1
            %            gDB = count3;
            %        case 2
            %            gDB = count4;
            %   end       
               
            %   searchFeatures(app, immagini, imageVector{i}, folderFeatures);
               
                %for m = 1:length(immagini)
                %    nameImageFolder = immagini(m).name;
                %    if(strcmp(imageVector{i}, nameImageFolder))
                %        imageFeatures = folderFeatures(m,:);
                %    end
                %end
                
            %   for k = 1:length(immagini)
            %        baseFileName = immagini(k).name;
               
                    %salvo dentro un vettore tutti i nomi delle immagini
            %        nomeRigaE(k, :) = {baseFileName}; 
               
                    %salvo dentro un vettore tutti i valori della distanza euclidea
            %        euDistance(k, :) = pdist2(folderFeatures(k,:), queryImage(1,:), 'euclidean');
            %    end
            %    euDistance = normalize(euDistance, 'range', [0,1]); 
                
            %    app.TRY1 = table(euDistance, 'RowNames', nomeRigaE);
            %    app.TRY1 = sortrows(app.TRY1);
               
            %    for j = 1:eta
            %        classeRisultato = extractBetween(app.TRY1.Properties.RowNames{j,:}, "-", ".gif");
            %        classeRisultato = str2num(classeRisultato{1:1});
            %        if(classeRisultato == classeImmagine)
            %            rDB = rDB + 1;
            %        end
            %        aux = aux + rDB/j;
            %    end               
            %    mAP = mAP + aux;
               
            %end
            %mAP = (100/length(immagini)) * mAP;  
            
            %fprintf('\n\nIl valore di mAP è: %.2f %', mAP);        
            
            fprintf('\n-----------------------------------------------------------')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChooseMomentTab = uitab(app.TabGroup);
            app.ChooseMomentTab.Title = 'Choose Moment';
            app.LoadFolderButton = uibutton(app.ChooseMomentTab, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Icon = 'Icon_Folder.png';
            app.LoadFolderButton.Text = {'   Load Folder'; ''};
            app.DirectoryPathLabel = uilabel(app.ChooseMomentTab);
            app.DirectoryPathLabel.Text = '   Directory Path';
            app.DirectoryContents = uitable(app.ChooseMomentTab);
            app.DirectoryContents.ColumnName = {'Directory Content'};
            app.DirectoryContents.RowName = {};
            app.Features_Dataset = uitable(app.ChooseMomentTab);
            app.Features_Dataset.ColumnName = {''};
            app.Features_Dataset.RowName = {};
            app.Euclidean_Results = uitable(app.ChooseMomentTab);
            app.Euclidean_Results.ColumnName = {'Euclidean Distance'};
            app.Euclidean_Results.RowName = {};
            app.LoadImageButton = uibutton(app.ChooseMomentTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'Icon_Folder.png';
            app.LoadImageButton.Text = {'   Load Image'; ''};
            app.photoPicked_3 = uilabel(app.ChooseMomentTab);
            app.photoPicked_3.Text = {'Photo Picked'; ''};
            app.EuclideanDistanceButton = uibutton(app.ChooseMomentTab, 'push');
            app.EuclideanDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @EuclideanDistanceButtonPushed, true);
            app.EuclideanDistanceButton.Text = {'Euclidean '; 'Distance'};
            app.GetOrderEditFieldLabel = uilabel(app.ChooseMomentTab);
            app.GetOrderEditFieldLabel.Visible = 'off';
            app.GetOrderEditFieldLabel.Text = 'Get Order';
            app.GetOrderEditField = uieditfield(app.ChooseMomentTab, 'numeric');
            app.GetOrderEditField.Visible = 'off';
            app.GetRepEditFieldLabel = uilabel(app.ChooseMomentTab);
            app.GetRepEditFieldLabel.Visible = 'off';
            app.GetRepEditFieldLabel.Text = {'Get Rep'; ''};
            app.GetRepEditField = uieditfield(app.ChooseMomentTab, 'numeric');
            app.GetRepEditField.Visible = 'off';
            app.SimilarityButton = uibutton(app.ChooseMomentTab, 'push');
            app.SimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarityButtonPushed, true);
            app.SimilarityButton.Text = 'Similarity ';
            app.Similarity_Results = uitable(app.ChooseMomentTab);
            app.Similarity_Results.ColumnName = {'Similarity Measure'};
            app.Similarity_Results.RowName = {};
            app.CalculateButton = uibutton(app.ChooseMomentTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Visible = 'off';
            app.CalculateButton.Text = {'Calculate'; ''};
            app.WhatmomentdoyouwanttocalculateButtonGroup = uibuttongroup(app.ChooseMomentTab);
            app.WhatmomentdoyouwanttocalculateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhatmomentdoyouwanttocalculateButtonGroupSelectionChanged, true);
            app.WhatmomentdoyouwanttocalculateButtonGroup.Title = 'What moment do you want to calculate?';
            app.Button = uiradiobutton(app.WhatmomentdoyouwanttocalculateButtonGroup);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '';
            app.Button.Value = true;
            app.ZernikeButton_2 = uiradiobutton(app.WhatmomentdoyouwanttocalculateButtonGroup);
            app.ZernikeButton_2.Text = 'Zernike';
            app.LegendreButton_2 = uiradiobutton(app.WhatmomentdoyouwanttocalculateButtonGroup);
            app.LegendreButton_2.Text = 'Legendre';
            app.ChebyshevButton = uiradiobutton(app.WhatmomentdoyouwanttocalculateButtonGroup);
            app.ChebyshevButton.Text = 'Chebyshev';
            app.HUButton_3 = uiradiobutton(app.WhatmomentdoyouwanttocalculateButtonGroup);
            app.HUButton_3.Text = 'HU';
            app.EuclideanPerformanceButton = uibutton(app.ChooseMomentTab, 'push');
            app.EuclideanPerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @EuclideanPerformanceButtonPushed, true);
            app.EuclideanPerformanceButton.Text = 'Euclidean Performance';
            app.SimilarityPerformanceButton = uibutton(app.ChooseMomentTab, 'push');
            app.SimilarityPerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarityPerformanceButtonPushed, true);
            app.SimilarityPerformanceButton.Text = {'Similarity Performance'; ''; ''};
            app.Performance_Euclide = uitable(app.ChooseMomentTab);
            app.Performance_Euclide.ColumnName = {'Performance Results'};
            app.Performance_Euclide.RowName = {};
            app.Performance_Similarita = uitable(app.ChooseMomentTab);
            app.Performance_Similarita.ColumnName = {'Performance Results'};
            app.Performance_Similarita.RowName = {};
            app.EuclideanResultsTab_2 = uitab(app.TabGroup);
            app.EuclideanResultsTab_2.Title = 'Euclidean Results';
            app.photo1_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo1_2, '')
            xlabel(app.photo1_2, '')
            ylabel(app.photo1_2, '')
            app.photo1_2.XTick = [];
            app.photo1_2.XTickLabel = '';
            app.photo1_2.YTick = [];
            app.photo2_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo2_2, '')
            xlabel(app.photo2_2, '')
            ylabel(app.photo2_2, '')
            app.photo2_2.XTick = [];
            app.photo2_2.XTickLabel = '';
            app.photo2_2.YTick = [];
            app.photo3_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo3_2, '')
            xlabel(app.photo3_2, '')
            ylabel(app.photo3_2, '')
            app.photo3_2.XTick = [];
            app.photo3_2.XTickLabel = '';
            app.photo3_2.YTick = [];
            app.photo4_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo4_2, '')
            xlabel(app.photo4_2, '')
            ylabel(app.photo4_2, '')
            app.photo4_2.XTick = [];
            app.photo4_2.XTickLabel = '';
            app.photo4_2.YTick = [];
            app.photo5_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo5_2, '')
            xlabel(app.photo5_2, '')
            ylabel(app.photo5_2, '')
            app.photo5_2.XTick = [];
            app.photo5_2.XTickLabel = '';
            app.photo5_2.YTick = [];
            app.photo6_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo6_2, '')
            xlabel(app.photo6_2, '')
            ylabel(app.photo6_2, '')
            app.photo6_2.XTick = [];
            app.photo6_2.XTickLabel = '';
            app.photo6_2.YTick = [];
            app.photo7_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo7_2, '')
            xlabel(app.photo7_2, '')
            ylabel(app.photo7_2, '')
            app.photo7_2.XTick = [];
            app.photo7_2.XTickLabel = '';
            app.photo7_2.YTick = [];
            app.photo8_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo8_2, '')
            xlabel(app.photo8_2, '')
            ylabel(app.photo8_2, '')
            app.photo8_2.XTick = [];
            app.photo8_2.XTickLabel = '';
            app.photo8_2.YTick = [];
            app.photo9_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo9_2, '')
            xlabel(app.photo9_2, '')
            ylabel(app.photo9_2, '')
            app.photo9_2.XTick = [];
            app.photo9_2.XTickLabel = '';
            app.photo9_2.YTick = [];
            app.photo10_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.photo10_2, '')
            xlabel(app.photo10_2, '')
            ylabel(app.photo10_2, '')
            app.photo10_2.XTick = [];
            app.photo10_2.XTickLabel = '';
            app.photo10_2.YTick = [];
            app.UIAxes_2 = uiaxes(app.EuclideanResultsTab_2);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.photoPicked_4 = uilabel(app.EuclideanResultsTab_2);
            app.photoPicked_4.Text = {'Photo Picked'; ''};
            app.dates1_2 = uitable(app.EuclideanResultsTab_2);
            app.dates1_2.ColumnName = {'Dissimilarity'};
            app.dates1_2.RowName = {};
            app.dates2_2 = uitable(app.EuclideanResultsTab_2);
            app.dates2_2.ColumnName = {'Dissimilarity'};
            app.dates2_2.RowName = {};
            app.dates3_2 = uitable(app.EuclideanResultsTab_2);
            app.dates3_2.ColumnName = {'Dissimilarity'};
            app.dates3_2.RowName = {};
            app.dates4_2 = uitable(app.EuclideanResultsTab_2);
            app.dates4_2.ColumnName = {'Dissimilarity'};
            app.dates4_2.RowName = {};
            app.dates5_2 = uitable(app.EuclideanResultsTab_2);
            app.dates5_2.ColumnName = {'Dissimilarity'};
            app.dates5_2.RowName = {};
            app.dates6_2 = uitable(app.EuclideanResultsTab_2);
            app.dates6_2.ColumnName = {'Dissimilarity'};
            app.dates6_2.RowName = {};
            app.dates7_2 = uitable(app.EuclideanResultsTab_2);
            app.dates7_2.ColumnName = {'Dissimilarity'};
            app.dates7_2.RowName = {};
            app.dates8_2 = uitable(app.EuclideanResultsTab_2);
            app.dates8_2.ColumnName = {'Dissimilarity'};
            app.dates8_2.RowName = {};
            app.dates9_2 = uitable(app.EuclideanResultsTab_2);
            app.dates9_2.ColumnName = {'Dissimilarity'};
            app.dates9_2.RowName = {};
            app.dates10_2 = uitable(app.EuclideanResultsTab_2);
            app.dates10_2.ColumnName = {'Dissimilarity'};
            app.dates10_2.RowName = {};
            app.SimilarityResultsTab = uitab(app.TabGroup);
            app.SimilarityResultsTab.Title = 'Similarity Results';
            app.photo1 = uiaxes(app.SimilarityResultsTab);
            title(app.photo1, '')
            xlabel(app.photo1, '')
            ylabel(app.photo1, '')
            app.photo1.XTick = [];
            app.photo1.XTickLabel = '';
            app.photo1.YTick = [];
            app.photo2 = uiaxes(app.SimilarityResultsTab);
            title(app.photo2, '')
            xlabel(app.photo2, '')
            ylabel(app.photo2, '')
            app.photo2.XTick = [];
            app.photo2.XTickLabel = '';
            app.photo2.YTick = [];
            app.photo3 = uiaxes(app.SimilarityResultsTab);
            title(app.photo3, '')
            xlabel(app.photo3, '')
            ylabel(app.photo3, '')
            app.photo3.XTick = [];
            app.photo3.XTickLabel = '';
            app.photo3.YTick = [];
            app.photo4 = uiaxes(app.SimilarityResultsTab);
            title(app.photo4, '')
            xlabel(app.photo4, '')
            ylabel(app.photo4, '')
            app.photo4.XTick = [];
            app.photo4.XTickLabel = '';
            app.photo4.YTick = [];
            app.photo5 = uiaxes(app.SimilarityResultsTab);
            title(app.photo5, '')
            xlabel(app.photo5, '')
            ylabel(app.photo5, '')
            app.photo5.XTick = [];
            app.photo5.XTickLabel = '';
            app.photo5.YTick = [];
            app.photo6 = uiaxes(app.SimilarityResultsTab);
            title(app.photo6, '')
            xlabel(app.photo6, '')
            ylabel(app.photo6, '')
            app.photo6.XTick = [];
            app.photo6.XTickLabel = '';
            app.photo6.YTick = [];
            app.photo7 = uiaxes(app.SimilarityResultsTab);
            title(app.photo7, '')
            xlabel(app.photo7, '')
            ylabel(app.photo7, '')
            app.photo7.XTick = [];
            app.photo7.XTickLabel = '';
            app.photo7.YTick = [];
            app.photo8 = uiaxes(app.SimilarityResultsTab);
            title(app.photo8, '')
            xlabel(app.photo8, '')
            ylabel(app.photo8, '')
            app.photo8.XTick = [];
            app.photo8.XTickLabel = '';
            app.photo8.YTick = [];
            app.photo9 = uiaxes(app.SimilarityResultsTab);
            title(app.photo9, '')
            xlabel(app.photo9, '')
            ylabel(app.photo9, '')
            app.photo9.XTick = [];
            app.photo9.XTickLabel = '';
            app.photo9.YTick = [];
            app.photo10 = uiaxes(app.SimilarityResultsTab);
            title(app.photo10, '')
            xlabel(app.photo10, '')
            ylabel(app.photo10, '')
            app.photo10.XTick = [];
            app.photo10.XTickLabel = '';
            app.photo10.YTick = [];
            app.UIAxes = uiaxes(app.SimilarityResultsTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.photoPicked = uilabel(app.SimilarityResultsTab);
            app.photoPicked.Text = {'Photo Picked'; ''};
            app.dates1 = uitable(app.SimilarityResultsTab);
            app.dates1.ColumnName = {'Similarity'};
            app.dates1.RowName = {};
            app.dates2 = uitable(app.SimilarityResultsTab);
            app.dates2.ColumnName = {'Similarity'};
            app.dates2.RowName = {};
            app.dates3 = uitable(app.SimilarityResultsTab);
            app.dates3.ColumnName = {'Similarity'};
            app.dates3.RowName = {};
            app.dates4 = uitable(app.SimilarityResultsTab);
            app.dates4.ColumnName = {'Similarity'};
            app.dates4.RowName = {};
            app.dates5 = uitable(app.SimilarityResultsTab);
            app.dates5.ColumnName = {'Similarity'};
            app.dates5.RowName = {};
            app.dates6 = uitable(app.SimilarityResultsTab);
            app.dates6.ColumnName = {'Similarity'};
            app.dates6.RowName = {};
            app.dates7 = uitable(app.SimilarityResultsTab);
            app.dates7.ColumnName = {'Similarity'};
            app.dates7.RowName = {};
            app.dates8 = uitable(app.SimilarityResultsTab);
            app.dates8.ColumnName = {'Similarity'};
            app.dates8.RowName = {};
            app.dates9 = uitable(app.SimilarityResultsTab);
            app.dates9.ColumnName = {'Similarity'};
            app.dates9.RowName = {};
            app.dates10 = uitable(app.SimilarityResultsTab);
            app.dates10.ColumnName = {'Similarity'};
            app.dates10.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moments_Applications_PROVE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end