classdef NewHuApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        LoadImageTab         matlab.ui.container.Tab
        LoadImageButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        AlgorithmHUButton_2  matlab.ui.control.Button
        Features_Image       matlab.ui.control.Table
        ImageLabel           matlab.ui.control.Label
        LoadFolderTab        matlab.ui.container.Tab
        LoadFolderButton     matlab.ui.control.Button
        DirectoryPathLabel   matlab.ui.control.Label
        DirectoryContents    matlab.ui.control.Table
        AlgorithmHUButton    matlab.ui.control.Button
        Features_Dataset     matlab.ui.control.Table
        Euclidean_Results    matlab.ui.control.Table
    end

    
    properties (Access = private)
        T % Table
        T1 % Table for Folder
        T2 % Table for Distance
    end
    
    methods (Access = private)
        
        % -------------------------- funzione Hu_Moments --------------------------
        function phi = Hu_Moments(~, F)
            %INVMOMENTS Compute invariant moments of image.
            %   PHI = INVMOMENTS(F) computes the moment invariants of the image
            %   F. PHI is a seven-element row vector containing the moment
            %   invariants as defined in equations (11.3-17) through (11.3-23) of
            %   Gonzalez and Woods, Digital Image Processing, 2nd Ed.
            %
            %   F must be a 2-D, real, nonsparse, numeric or logical matrix.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/11/21 14:39:19 $
            
            if (~ismatrix(F)) || issparse(F) || ~isreal(F) || ~(isnumeric(F) || islogical(F))
               error(['F must be a 2-D, real, nonsparse, numeric or logical ' 'matrix.']);                       
            end
            
            F = double(F);
            phi = compute_phi(compute_eta(compute_m(F)));            
                
           
            % -------------------------- funzione compute_m --------------------------
            function m = compute_m(F)
                [M, N] = size(F);
                [x, y] = meshgrid(1:N, 1:M);
                  
                % Turn x, y, and F into column vectors to make the summations a bit
                % easier to compute in the following.
                x = x(:);
                y = y(:);
                F = F(:);
                  
                % DIP equation (11.3-12)
                m.m00 = sum(F);
                % Protect against divide-by-zero warnings.
                if (m.m00 == 0)
                   m.m00 = eps;
                end
                % The other central moments:  
                m.m10 = sum(x .* F);
                m.m01 = sum(y .* F);
                m.m11 = sum(x .* y .* F);
                m.m20 = sum(x.^2 .* F);
                m.m02 = sum(y.^2 .* F);
                m.m30 = sum(x.^3 .* F);
                m.m03 = sum(y.^3 .* F);
                m.m12 = sum(x .* y.^2 .* F);
                m.m21 = sum(x.^2 .* y .* F);            
            end
            
            % -------------------------- funzione compute_eta --------------------------
            function e = compute_eta(m)
    
                % DIP equations (11.3-14) through (11.3-16).
                
                xbar = m.m10 / m.m00;
                ybar = m.m01 / m.m00;
                
                e.eta11 = (m.m11 - ybar*m.m10) / m.m00^2;
                e.eta20 = (m.m20 - xbar*m.m10) / m.m00^2;
                e.eta02 = (m.m02 - ybar*m.m01) / m.m00^2;
                e.eta30 = (m.m30 - 3 * xbar * m.m20 + 2 * xbar^2 * m.m10) / m.m00^2.5;
                e.eta03 = (m.m03 - 3 * ybar * m.m02 + 2 * ybar^2 * m.m01) / m.m00^2.5;
                e.eta21 = (m.m21 - 2 * xbar * m.m11 - ybar * m.m20 + 2 * xbar^2 * m.m01) / m.m00^2.5;                       
                e.eta12 = (m.m12 - 2 * ybar * m.m11 - xbar * m.m02 + 2 * ybar^2 * m.m10) / m.m00^2.5;                               
            end
            
            % -------------------------- funzione compute_phi --------------------------
            function phi = compute_phi(e)
                % DIP equations (11.3-17) through (11.3-23).
                
                phi(1) = e.eta20 + e.eta02;
                phi(2) = (e.eta20 - e.eta02)^2 + 4*e.eta11^2;
                phi(3) = (e.eta30 - 3*e.eta12)^2 + (3*e.eta21 - e.eta03)^2;
                phi(4) = (e.eta30 + e.eta12)^2 + (e.eta21 + e.eta03)^2;
                phi(5) = (e.eta30 - 3*e.eta12) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta21 - e.eta03) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );
                phi(6) = (e.eta20 - e.eta02) * ( (e.eta30 + e.eta12)^2 - ...
                                                 (e.eta21 + e.eta03)^2 ) + ...
                         4 * e.eta11 * (e.eta30 + e.eta12) * (e.eta21 + e.eta03);
                phi(7) = (3*e.eta21 - e.eta03) * (e.eta30 + e.eta12) * ...
                         ( (e.eta30 + e.eta12)^2 - 3*(e.eta21 + e.eta03)^2 ) + ...
                         (3*e.eta12 - e.eta30) * (e.eta21 + e.eta03) * ...
                         ( 3*(e.eta30 + e.eta12)^2 - (e.eta21 + e.eta03)^2 );           
            end
        end    
        
        % ---------------- Funzione per trasformare la figura in 2D ----------------
        %function grayscale(app)
        %    global image1
        %    image1 = rgb2gray(image1);
        %end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            % variabili globali 
            global path            
            
            path = uigetdir;                       % 'uigetdir' recupera il path in base alla cartella selezionata dall'utente
            
            app.UIFigure.Visible = 'off';          % queste due righe gestiscono i casi in cui l'app rimane sopra tutte le finestre
            app.UIFigure.Visible = 'on';           % e i casi in cui rimane in background
            
            % inserito lo stesso controllo presente nell'inserimento
            % dell'immagine, in modo che non venga generato un errore nel
            % caso in cui l'utente dovesse premere per sbaglio sul tasto
            % per annullare la selezione di una cartella
            
            if(ischar(path))
                app.DirectoryPathLabel.Text = path;    % il path della cartella salvato in precedenza viene mostrato nella label 

                a = dir(path);                                      % recupero il contenuto della cartella attraverso il path e la funzione 'dir'
                imageVector = {a(:).name}';                         % recupero tutti i nomi dei vari file presenti nella cartella e li salvo in un array
                imageVector(ismember(imageVector,{'.','..'})) = []; % rimuovo eventuali 'punti' o 'doppi punti' che non sono necessari

                app.DirectoryContents.Data = imageVector;           % mostro il contenuto della cartella nella tabella "Directory Contents"
            end                                         
        end

        % Button pushed function: AlgorithmHUButton
        function AlgorithmHUButtonPushed(app, event)
            % variabili globali         
            global path
            global Risultati
            global features
            
            folderFeatures = [];
            
            % ciclo per scorrere il vettore in cui sono contenute le
            % immagini, applicare la funzione e salvare ogni valore in una
            % riga della seconda tabella
            
            pattern = fullfile(path, '*.tiff'); % -------- da automatizzare --------
            immagini = dir(pattern);
            
            app.UIFigure.Visible = 'off';          
            app.UIFigure.Visible = 'on'; 
            
            for k = 1:length(immagini)
                baseFileName = immagini(k).name;
                fullFileName = fullfile(path, baseFileName);
                image = imread(fullFileName);
                %grayscale(app);
                nomeRiga = {baseFileName};
                
                folderFeatures = app.Hu_Moments(image);
                
                %app.Features_Dataset.Data(k, 1:7) = Risultati; % tabella con le features per ogni immagine della cartella
                
                % pezzo che applica il calcolo della distanza euclidea tra
                % le features di ogni immagine del dataset e quelle
                % dell'immagine scelta dall'utente
                % D = pdist2(Risultati, features, 'euclidean');
                % app.Euclidean_Results.Data(k, 1) = D;
            end
            
            app.T1 = table(folderFeatures, 'RowNames', nomeRiga);
            app.Features_Dataset.Data = app.T1{:,:}; % salvo i valori ottenuti all'interno della tabella\

            app.Features_Dataset.RowName = app.T1.Properties.RowNames;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global image1     
            
            filterImage = {'*.tiff;*.jpg;*.png;*.gif;*.tif;*.bmp', 'All Image Files'}; %imposto il filtro per la scelta delle immagini (in base al tipo di file)
            
            [file, path] =  uigetfile(filterImage); % l'utente sceglie un'immagine sulla base del filtro presente
            
            % con questo controllo faccio in modo che l'applicazione non
            % vada avanti fino a quando l'utente non inserisce un'immagine,
            % evitando il crash nel caso in cui decida di provare a uscire
            % dalla finestra di dialogo che viene generata
            if(ischar(path))
                fullpathname = strcat(path, file);
                image1 = imread(fullpathname);
            end
            
            imshow(image1, 'Parent', app.UIAxes); % mostro a video l'immagine al posto del piano
        end

        % Button pushed function: AlgorithmHUButton_2
        function AlgorithmHUButton_2Pushed(app, event)
            global image1
            
            imageFeatures = {};
            nomeRiga = {'Ímmagine'};
            
            imageFeatures = app.Hu_Moments(image1);   % applico l'algoritmo HU sull'imagine scelta
            
            app.T = table(imageFeatures, 'RowNames', nomeRiga);
            
            app.Features_Image.Data = app.T{:,:}; % salvo i valori ottenuti all'interno della tabella\

            app.Features_Image.RowName = app.T.Properties.RowNames;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImageTab = uitab(app.TabGroup);
            app.LoadImageTab.Title = 'Load Image';
            app.LoadImageButton = uibutton(app.LoadImageTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'Icon_Folder.png';
            app.LoadImageButton.Text = {'   Load Image'; ''};
            app.UIAxes = uiaxes(app.LoadImageTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.AlgorithmHUButton_2 = uibutton(app.LoadImageTab, 'push');
            app.AlgorithmHUButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmHUButton_2Pushed, true);
            app.AlgorithmHUButton_2.Text = 'Algorithm HU';
            app.Features_Image = uitable(app.LoadImageTab);
            app.Features_Image.ColumnName = {'Valore 1'; 'Valore 2'; 'Valore 3'; 'Valore 4'; 'Valore 5'; 'Valore 6'; 'Valore 7'};
            app.Features_Image.RowName = {};
            app.ImageLabel = uilabel(app.LoadImageTab);
            app.ImageLabel.Text = 'Image';
            app.LoadFolderTab = uitab(app.TabGroup);
            app.LoadFolderTab.Title = 'Load Folder';
            app.LoadFolderButton = uibutton(app.LoadFolderTab, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Icon = 'Icon_Folder.png';
            app.LoadFolderButton.Text = {'   Load Folder'; ''};
            app.DirectoryPathLabel = uilabel(app.LoadFolderTab);
            app.DirectoryPathLabel.Text = '   Directory Path';
            app.DirectoryContents = uitable(app.LoadFolderTab);
            app.DirectoryContents.ColumnName = {'Directory Content'};
            app.DirectoryContents.RowName = {};
            app.AlgorithmHUButton = uibutton(app.LoadFolderTab, 'push');
            app.AlgorithmHUButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmHUButtonPushed, true);
            app.AlgorithmHUButton.Text = {'Algorithm HU'; ''};
            app.Features_Dataset = uitable(app.LoadFolderTab);
            app.Features_Dataset.ColumnName = {'Valore 1'; 'Valore 2'; 'Valore 3'; 'Valore 4'; 'Valore 5'; 'Valore 6'; 'Valore 7'};
            app.Features_Dataset.RowName = {};
            app.Euclidean_Results = uitable(app.LoadFolderTab);
            app.Euclidean_Results.ColumnName = {'Euclidean Distance'};
            app.Euclidean_Results.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewHuApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end